classdef GUI_PBL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GeometraSatliteButtonGroup   matlab.ui.container.ButtonGroup
        HexagonalButton              matlab.ui.control.RadioButton
        RectangularButton            matlab.ui.control.RadioButton
        PotenciaRequeridaWEditFieldLabel  matlab.ui.control.Label
        PotenciaRequeridaWEditField  matlab.ui.control.NumericEditField
        PANELESSOLARESLabel          matlab.ui.control.Label
        AceptarButton                matlab.ui.control.Button
        MaterialButtonGroup          matlab.ui.container.ButtonGroup
        AluminioButton               matlab.ui.control.RadioButton
        MatCompuestoButton           matlab.ui.control.RadioButton
        MEDIDASDELOSPANELESPanel     matlab.ui.container.Panel
        LongitudbasedelsatlitelbmmLabel  matlab.ui.control.Label
        EditField                    matlab.ui.control.NumericEditField
        LongitudalturadelsatlitelhmmLabel  matlab.ui.control.Label
        EditField_2                  matlab.ui.control.NumericEditField
        f_lbLabel                    matlab.ui.control.Label
        f_lbSlider_2                 matlab.ui.control.Slider
        f_lhLabel                    matlab.ui.control.Label
        f_lhSlider                   matlab.ui.control.Slider
        Image                        matlab.ui.control.Image
        CONFIGURACINDELPANELPanel    matlab.ui.container.Panel
        ColumnasSpinnerLabel         matlab.ui.control.Label
        ColumnasSpinner              matlab.ui.control.Spinner
        FilasSpinnerLabel            matlab.ui.control.Label
        FilasSpinner                 matlab.ui.control.Spinner
        CelulasSpinnerLabel          matlab.ui.control.Label
        CelulasSpinner               matlab.ui.control.Spinner
        EspesormmDropDownLabel       matlab.ui.control.Label
        EspesormmDropDown            matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        material 
        geometria
        potencia
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AceptarButton
        function AceptarButtonPushed(app, event)
            
           app.material=app.MaterialButtonGroup.SelectedObject;
           app.potencia = app.PotenciaRequeridaWEditField.Value; 
           app.geometria = app.GeometraSatliteButtonGroup.SelectedObject;
 
                if app.material == app.AluminioButton 
                    material_usado = 'Aluminio';
                    
                else
                    material_usado = 'Compuesto';
                end
            %material_usado=app.material;
            sobredim_h = app.f_lhSlider.Value; %sobredimension altura
            sobredim_b = app.f_lbSlider_2.Value; %sobredimension base
            Altura =  app.EditField.Value ;        
            Longitud = app.EditField_2.Value ;
            Panel_Dim_Y = Altura*(sobredim_h);
            Panel_Dim_X = Longitud*(sobredim_b);
            
            % Bisagra
            Hinge_Dim_X = 2*9;

            
            % Configuracion del panel
            Cupon_Cells = app.CelulasSpinner.Value;
            Cupon_N_X = app.ColumnasSpinner.Value;
            Cupon_N_Y = app.FilasSpinner.Value;
            
            
            % Margenes entre cupon y borde del panel
            Offset_X = 1;
            Offset_Y = 1;
            
            if strcmp(material_usado,'Aluminio')
                % Dimensiones de un cupon
                Cupon_Dim_X = 84;    % mm
                Cupon_Dim_Y =  (Cupon_Cells - 1)*41 + 52;
                
                % Dimensiones finales del panel
                Panel_Dim_X = Cupon_N_X*Cupon_Dim_X + 1*(Cupon_N_X - 1)...
                             + 2*Offset_X + 2*Hinge_Dim_X;
                Panel_Dim_Y = Cupon_N_Y*Cupon_Dim_Y + 1*(Cupon_N_Y - 1)...
                             + 2*Offset_Y;
                
                % Espesor
                Aluminium_Thickness = str2double(app.EspesormmDropDown.Value);
            else
                Cupon_Dim_X = 80;    % mm
                Cupon_Dim_Y = 40;

                espaciado_h = 10;
                espaciado_v = 10;
            end
        
        %{
            Nc_h = round((Panel_Dim_X - 2*Offset_X + espaciado_h)/(Cupon_Dim_X + espaciado_h)-0.5);    % el -0.5 es para truncar
            Nc_v = round((Panel_Dim_Y - 2*Offset_Y + espaciado_v)/(Cupon_Dim_Y + espaciado_v)-0.5);
    
            D_h = Cupon_Dim_X + espaciado_h;
            D_v = Cupon_Dim_Y + espaciado_v;
            x0_cel = (Cupon_Dim_X + espaciado_h)*(Nc_h-1)/2;
            y0_cel = (Cupon_Dim_Y + espaciado_v)*(Nc_v-1)/2;
            
            
            %%POTENCIA%%
            %INFORMACIÓN CELULAS SOLARES UPM SAT2 
            
            I_celula=0.487;
            V_celula=2.371;
           
    
            % Por ahora voy a suponer Nc_h van en serie y Nc_v van en parelo 
            %pero eso dependera de cual es el Voltaje de salida que necesitemos
            V=V_celula*Nc_h;
            I= I_celula*Nc_v;
            
            P=V*I*2; %generada por los dos paneles
        %}
        
        
        %% SOBREESCRIBIR EXCEL DE PARAMETROS
        param = table(Panel_Dim_X, Panel_Dim_Y, {'Aluminium'}, Aluminium_Thickness, ...
            {'NA'}, {'Hexagono'}, Cupon_N_X, Cupon_N_Y, Cupon_Cells);
        %{
            param = [{material_usado}, {Panel_Dim_X}, {Panel_Dim_Y},...
                 {Nc_h}, {Nc_v}, {D_h}, {D_v}, {x0_cel}, {y0_cel}];
        %}
        xlswrite('400_Parameters_DRV.xls', table2cell(param), 1, 'A2');
        %xlswrite('400_Parameters_DRV.xls', table2cell(param), 1, 'A2');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GeometraSatliteButtonGroup = uibuttongroup(app.UIFigure);
            app.GeometraSatliteButtonGroup.Title = 'Geometría Satélite';
            app.HexagonalButton = uiradiobutton(app.GeometraSatliteButtonGroup);
            app.HexagonalButton.Text = 'Hexagonal';
            app.HexagonalButton.Value = true;
            app.RectangularButton = uiradiobutton(app.GeometraSatliteButtonGroup);
            app.RectangularButton.Text = 'Rectangular';
            app.PotenciaRequeridaWEditFieldLabel = uilabel(app.UIFigure);
            app.PotenciaRequeridaWEditFieldLabel.Text = 'Potencia Requerida [W]';
            app.PotenciaRequeridaWEditField = uieditfield(app.UIFigure, 'numeric');
            app.PANELESSOLARESLabel = uilabel(app.UIFigure);
            app.PANELESSOLARESLabel.Text = 'PANELES SOLARES';
            app.AceptarButton = uibutton(app.UIFigure, 'push');
            app.AceptarButton.ButtonPushedFcn = createCallbackFcn(app, @AceptarButtonPushed, true);
            app.AceptarButton.Text = 'Aceptar';
            app.MaterialButtonGroup = uibuttongroup(app.UIFigure);
            app.MaterialButtonGroup.Title = 'Material';
            app.AluminioButton = uiradiobutton(app.MaterialButtonGroup);
            app.AluminioButton.Text = 'Aluminio';
            app.AluminioButton.Value = true;
            app.MatCompuestoButton = uiradiobutton(app.MaterialButtonGroup);
            app.MatCompuestoButton.Text = 'Mat. Compuesto';
            app.MEDIDASDELOSPANELESPanel = uipanel(app.UIFigure);
            app.MEDIDASDELOSPANELESPanel.Title = 'MEDIDAS DE LOS PANELES';
            app.LongitudbasedelsatlitelbmmLabel = uilabel(app.MEDIDASDELOSPANELESPanel);
            app.LongitudbasedelsatlitelbmmLabel.Text = 'Longitud base del satélite (lb) [mm]';
            app.EditField = uieditfield(app.MEDIDASDELOSPANELESPanel, 'numeric');
            app.LongitudalturadelsatlitelhmmLabel = uilabel(app.MEDIDASDELOSPANELESPanel);
            app.LongitudalturadelsatlitelhmmLabel.Text = 'Longitud altura del satélite (lh) [mm]';
            app.EditField_2 = uieditfield(app.MEDIDASDELOSPANELESPanel, 'numeric');
            app.f_lbLabel = uilabel(app.MEDIDASDELOSPANELESPanel);
            app.f_lbLabel.Text = 'f_lb';
            app.f_lbSlider_2 = uislider(app.MEDIDASDELOSPANELESPanel);
            app.f_lbSlider_2.Limits = [1 1.1];
            app.f_lbSlider_2.Value = 1;
            app.f_lhLabel = uilabel(app.MEDIDASDELOSPANELESPanel);
            app.f_lhLabel.Text = 'f_lh';
            app.f_lhSlider = uislider(app.MEDIDASDELOSPANELESPanel);
            app.f_lhSlider.Limits = [1 1.1];
            app.f_lhSlider.Value = 1;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'image18.png';
            app.CONFIGURACINDELPANELPanel = uipanel(app.UIFigure);
            app.CONFIGURACINDELPANELPanel.Title = 'CONFIGURACIÓN DEL PANEL';
            app.ColumnasSpinnerLabel = uilabel(app.CONFIGURACINDELPANELPanel);
            app.ColumnasSpinnerLabel.Text = 'Columnas';
            app.ColumnasSpinner = uispinner(app.CONFIGURACINDELPANELPanel);
            app.ColumnasSpinner.Limits = [2 6];
            app.ColumnasSpinner.Value = 2;
            app.FilasSpinnerLabel = uilabel(app.CONFIGURACINDELPANELPanel);
            app.FilasSpinnerLabel.Text = 'Filas';
            app.FilasSpinner = uispinner(app.CONFIGURACINDELPANELPanel);
            app.FilasSpinner.Limits = [2 6];
            app.FilasSpinner.Value = 2;
            app.CelulasSpinnerLabel = uilabel(app.CONFIGURACINDELPANELPanel);
            app.CelulasSpinnerLabel.Text = 'Celulas ';
            app.CelulasSpinner = uispinner(app.CONFIGURACINDELPANELPanel);
            app.CelulasSpinner.Limits = [1 10];
            app.CelulasSpinner.Value = 1;
            app.EspesormmDropDownLabel = uilabel(app.CONFIGURACINDELPANELPanel);
            app.EspesormmDropDownLabel.Text = 'Espesor [mm]';
            app.EspesormmDropDown = uidropdown(app.CONFIGURACINDELPANELPanel);
            app.EspesormmDropDown.Items = {'1.5', '2.5'};
            app.EspesormmDropDown.Value = '1.5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_PBL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end