classdef GUI_PANELES < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        GeometrayMaterialTab           matlab.ui.container.Tab
        PANELESSOLARESLabel            matlab.ui.control.Label
        MaterialButtonGroup            matlab.ui.container.ButtonGroup
        EspesorButtonGroup             matlab.ui.container.ButtonGroup
        mmButton                       matlab.ui.control.RadioButton
        mmButton_2                     matlab.ui.control.RadioButton
        AluminioENAW7075CheckBox       matlab.ui.control.CheckBox
        MaterialCompuestoCheckBox      matlab.ui.control.CheckBox
        NcleoButtonGroup               matlab.ui.container.ButtonGroup
        PolimeroHRH10Button            matlab.ui.control.RadioButton
        SandwichAluminio5052Button     matlab.ui.control.RadioButton
        SandwichAlumnio5056Button      matlab.ui.control.RadioButton
        EspesorncleommSpinnerLabel     matlab.ui.control.Label
        EspesorncleommSpinner          matlab.ui.control.Spinner
        Fibra914T300ButtonGroup        matlab.ui.container.ButtonGroup
        Paratodosloscasoselespesordelafibraser05mmLabel  matlab.ui.control.Label
        GeometraSatlitePanel           matlab.ui.container.Panel
        LongitudbasedelsatlitelbmmLabel  matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        LongitudalturadelsatlitelhmmLabel  matlab.ui.control.Label
        EditField_2                    matlab.ui.control.NumericEditField
        f_lbLabel                      matlab.ui.control.Label
        f_lbSlider                     matlab.ui.control.Slider
        f_lhLabel                      matlab.ui.control.Label
        f_lhSlider                     matlab.ui.control.Slider
        ButtonGroup                    matlab.ui.container.ButtonGroup
        HexagonalButton_2              matlab.ui.control.RadioButton
        RectangularButton_2            matlab.ui.control.RadioButton
        SobredimensinmximapermitidaLabel  matlab.ui.control.Label
        PotenciaTab                    matlab.ui.container.Tab
        HTML                           matlab.ui.control.HTML
        HTML2                          matlab.ui.control.HTML
        ConfiguracinclulassolaresencadapanelButtonGroup  matlab.ui.container.ButtonGroup
        ClculodePotenciaButton         matlab.ui.control.Button
        MaterialCompuestoPanel         matlab.ui.container.Panel
        NmerodecuponesporcolumnaSpinner_2Label  matlab.ui.control.Label
        NmerodecuponesporcolumnaSpinner_2  matlab.ui.control.Spinner
        NmerodeclulasporcupnSpinner_2  matlab.ui.control.Spinner
        NmerodeclulasporcupnSpinner_2Label  matlab.ui.control.Label
        NmerodecolumnasSpinner_2Label  matlab.ui.control.Label
        NmerodecolumnasSpinner_2       matlab.ui.control.Spinner
        NmerodepanelesdesplegadosporladoLabel_2  matlab.ui.control.Label
        NmerodepanelesdesplegadosporladoSpinner  matlab.ui.control.Spinner
        AlumnioPanel                   matlab.ui.container.Panel
        NmerodecuponesporcolumnaLabel_2  matlab.ui.control.Label
        NmerodecuponesporcolumnaSpinner  matlab.ui.control.Spinner
        NmerodecolumnasSpinnerLabel    matlab.ui.control.Label
        NmerodecolumnasSpinner         matlab.ui.control.Spinner
        NmerodeclulasporcupnLabel_2    matlab.ui.control.Label
        NmerodeclulasporcupnSpinner    matlab.ui.control.Spinner
        PotenciaWTextAreaLabel         matlab.ui.control.Label
        PotenciaWTextArea              matlab.ui.control.TextArea
        Image                          matlab.ui.control.Image
        ConexioneselctricaspaneldealuminioLabel  matlab.ui.control.Label
        ConexioneselctricaspaneldealuminioLabel_2  matlab.ui.control.Label
        VmpVEditFieldLabel             matlab.ui.control.Label
        VmpVEditField                  matlab.ui.control.NumericEditField
        ImpAEditFieldLabel             matlab.ui.control.Label
        ImpAEditField                  matlab.ui.control.NumericEditField
        TensineIntensidaddelpanelenelpuntodemximapotenciaT28CLabel  matlab.ui.control.Label
        MostrarinformacinconexioneselctricasCheckBox  matlab.ui.control.CheckBox
        ConexioneselctricaspaneldematerialcompuestoLabel  matlab.ui.control.Label
        ClulasenunamismacolumanaserieLabel  matlab.ui.control.Label
        FiguraRepresentacindelasclulasdecadapanelLabel  matlab.ui.control.Label
        DespliegueyorientacinTab       matlab.ui.container.Tab
        HTML3                          matlab.ui.control.HTML
        PanelesDesplagadosButtonGroup  matlab.ui.container.ButtonGroup
        SCheckBox                      matlab.ui.control.CheckBox
        NoCheckBox                     matlab.ui.control.CheckBox
        ngulodeorientacinLabel         matlab.ui.control.Label
        angulodeorientacinEditField    matlab.ui.control.NumericEditField
        PanelesOrientadosButtonGroup   matlab.ui.container.ButtonGroup
        SCheckBox_2                    matlab.ui.control.CheckBox
        NoCheckBox_2                   matlab.ui.control.CheckBox
        Imagenodesplegado              matlab.ui.control.Image
        Imagedesplegado                matlab.ui.control.Image
        GuardarcambiosButton           matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AluminioENAW7075CheckBox
        function AluminioENAW7075CheckBoxValueChanged(app, event)
            value = app.AluminioENAW7075CheckBox.Value;
            
            %CHECK BUTTON DEL ALUMINIO DE LA PRIMERA PESTAÑA
            %ESTA PARTE AFECTA A LA PARTE DE LAS OPCIONES DEL MATERIAL 

            switch value
                
                case 0
                    app.MaterialCompuestoCheckBox.Value=1;
                    
                    %Hace visible el panel correspondiente al material
                    app.NcleoButtonGroup.Visible = 'on';
                    app.Fibra914T300ButtonGroup.Visible = 'on';
                    app.EspesorButtonGroup.Visible = 'off';
                    

                case 1
                    app.MaterialCompuestoCheckBox.Value=0;
                    
                    %Hace visible el panel correspondiente al material
                    app.EspesorButtonGroup.Visible = 'on';
                    app.NcleoButtonGroup.Visible = 'off';
                    app.Fibra914T300ButtonGroup.Visible = 'off';


            end
            
            %ESTA PARTE AFECTA A UNICAMENTE A LA PARTE DE LA ELECCIÓN DE
            %CÉLULAS
            if (app.AluminioENAW7075CheckBox.Value)
                
                    %Para que no puedan estar los dos Checkbuttom señalados
                    %a la vez
                    app.MaterialCompuestoCheckBox.Value=0;
                    

                    %Hace visible el panel correspondiente de las
                    %conexiones eléctricas
                    app.MaterialCompuestoPanel.Visible =  'off';
                    app.AlumnioPanel.Visible = 'on'; 
                    
                    
            elseif (app.MaterialCompuestoCheckBox.Value)
                
                    %Para que no puedan estar los dos Checkbuttom señalados
                    %a la vez
                    app.AluminioENAW7075CheckBox.Value=0;
                    
                    %Hace visible el panel correspondiente de las
                    %conexiones eléctricas

                    app.MaterialCompuestoPanel.Visible =  'on';
                    app.AlumnioPanel.Visible = 'off'; 


            else

                
            end
            
%                   Obligar a actualizar la info de las conexiones
%                     electricas en caso de que se deje el check puesto -NO
%                     VA
%                     
%             if (app.MostrarinformacinconexioneselctricasCheckBox.Value)
%                 
%                 app.MostrarinformacinconexioneselctricasCheckBox.Value=0;
%                 app.MostrarinformacinconexioneselctricasCheckBox.Value=1;
%                 
%             else
%                         
%                 app.MostrarinformacinconexioneselctricasCheckBox.Value=1;
%                 app.MostrarinformacinconexioneselctricasCheckBox.Value=0;
%                         
%             end
%                    
            
        end

        % Value changed function: MaterialCompuestoCheckBox
        function MaterialCompuestoCheckBoxValueChanged(app, event)
            value = app.MaterialCompuestoCheckBox.Value;

            %CHECK BUTTON DEL ALUMINIO DE LA PRIMERA PESTAÑA
            %ESTA PARTE AFECTA A LA PARTE DE LAS OPCIONES DEL MATERIAL 
                      
            switch value
                
                case 0
                    app.AluminioENAW7075CheckBox.Value = 1;
                    
                    %Hace visible el panel correspondiente al material
                    app.EspesorButtonGroup.Visible = 'on';
                    app.NcleoButtonGroup.Visible = 'off';
                    app.Fibra914T300ButtonGroup.Visible = 'off';


                case 1
                    
                    app.AluminioENAW7075CheckBox.Value = 0;
                    
                    %Hace visible el panel correspondiente al material
                    app.NcleoButtonGroup.Visible = 'on';
                    app.Fibra914T300ButtonGroup.Visible = 'on';
                    app.EspesorButtonGroup.Visible = 'off';

                    
            end
            
            %ESTA PARTE AFECTA A UNICAMENTE A LA PARTE DE LA ELECCIÓN DE
            %CÉLULAS
            
            if (app.MaterialCompuestoCheckBox.Value)
                
                    %Para que no puedan estar los dos Checkbuttom señalados
                    %a la vez
                    app.AluminioENAW7075CheckBox.Value=0;
                    
                    %Hace visible el panel correspondiente de las
                    %conexiones eléctricas
                    app.MaterialCompuestoPanel.Visible =  'on';
                    app.AlumnioPanel.Visible = 'off'; 
                    
            elseif (app.AluminioENAW7075CheckBox.Value)
                    
                    %Para que no puedan estar los dos Checkbuttom señalados
                    %a la vez
                    app.MaterialCompuestoCheckBox.Value=0;
                    
                     %Hace visible el panel correspondiente de las
                    %conexiones eléctricas
                    app.MaterialCompuestoPanel.Visible =  'on';
                    app.AlumnioPanel.Visible = 'off';                    
             else


            end
            
                    %Obligar a actualizar la info de las conexiones
                    %electricas en caso de que se deje el check puesto NO VA 
%                     
%                     if (app.MostrarinformacinconexioneselctricasCheckBox.Value)
%                         app.MostrarinformacinconexioneselctricasCheckBox.Value=0;
%                         app.MostrarinformacinconexioneselctricasCheckBox.Value=1;
%                     else
%                         
%                         app.MostrarinformacinconexioneselctricasCheckBox.Value=1;
%                         app.MostrarinformacinconexioneselctricasCheckBox.Value=0;
%                         
%                     end
                
        end

        % Button pushed function: ClculodePotenciaButton
        function ClculodePotenciaButtonPushed(app, event)
           
            %Botón que calcula la potencia, las tensión e intensidad en
            %funcion de las conexiones eléctricas y muestra un
            %esquematicado de la posición geométrica de las celulas.
            
            
            if (app.MaterialCompuestoCheckBox.Value)
                
                %Calculo de la potencia con el número de células elegidas.
               
               cell_number = 2*app.NmerodecuponesporcolumnaSpinner_2.Value*app.NmerodeclulasporcupnSpinner_2.Value*app.NmerodecolumnasSpinner_2.Value*app.NmerodepanelesdesplegadosporladoSpinner.Value;
               Area = 0.003018*cell_number;
               Power = Area*0.294*1367;
               
               %Este valor es el necesario para la función que representa
               %las células solares
               n_row = app.NmerodecuponesporcolumnaSpinner_2.Value*app.NmerodeclulasporcupnSpinner_2.Value;
               n_col = app.NmerodecolumnasSpinner_2.Value;
               
               %Cálculo de la tensión y de la intensidad para las
               %conexiones eléctricas del material compuesto.
               
               app.VmpVEditField.Value = app.NmerodeclulasporcupnSpinner_2.Value*2.409;
               app.ImpAEditField.Value = app.NmerodecuponesporcolumnaSpinner_2.Value*app.NmerodecolumnasSpinner_2.Value*app.NmerodepanelesdesplegadosporladoSpinner.Value*0.5029;
               
               
            else  %Para el caso del aluminio
               
                
               %Calculo de la potencia con el número de células elegidas.
               cell_number = 2*app.NmerodeclulasporcupnSpinner.Value*app.NmerodecuponesporcolumnaSpinner.Value*app.NmerodecolumnasSpinner.Value*app.NmerodepanelesdesplegadosporladoSpinner.Value;
               Area = 0.003018*cell_number;
               Power = Area*0.294*1367;
               
               
               %Este valor es el necesario para la función que representa
               %las células solares
               n_row = app.NmerodeclulasporcupnSpinner.Value*app.NmerodecuponesporcolumnaSpinner.Value;
               n_col = app.NmerodecolumnasSpinner.Value;
               
               %Cálculo de la tensión y de la intensidad para las
               %conexiones eléctricas del material compuesto.
               app.VmpVEditField.Value = app.NmerodeclulasporcupnSpinner.Value*2.409;
               app.ImpAEditField.Value = app.NmerodecuponesporcolumnaSpinner.Value*app.NmerodecolumnasSpinner.Value*app.NmerodepanelesdesplegadosporladoSpinner.Value*0.5029;
            
           end

          
         app.PotenciaWTextArea.Value = num2str(Power); %Valor que sale por pantalla
         
         %%FUNCIÓN PARA REPRESENTAR LA POSICIÓN DE LAS CELULAS SOLARES%%


            %Geometría de la célula
            l1 = 2;
            l2 = 1;
            l4 = 0.25;
            l3 = l2-l4;
            margin = l4; %margenes entre células
            
            %Representación de la célula
            points_x = [0.0,  l1, l1, l1-l4  l4, 0.0, 0.0];
            points_y = [0.0, 0.0, l3,    l2, l2,  l3, 0.0];
            
            
            h=figure('visible','off');
            hold on;
            
            %Pinta todas las células
            for i_row = 1: n_row
                for i_col = 1: n_col
                    x_transf = margin + points_x + (l1+margin)*(i_col-1);
                    y_transf = margin + points_y + (l2+margin)*(i_row-1);
                    h=plot(x_transf, y_transf, 'k', "linewidth", 2.0);      
                    
                end
            end
            
            
            %Pinta los bordes 
            
            box 
            max_x = (l1+margin)*(n_col) + margin;
            max_y = (l2+margin)*(n_row) + margin;
            
            box_x = [0.0, max_x, max_x, 0.0, 0.0];
            box_y = [0.0, 0.0, max_y, max_y, 0.0];
            h=plot(box_x, box_y, 'b', "linewidth", 1.0);
            
            xlim([0.0, max_x])
            ylim([0.0, max_y])
            box off
            set(gca,'visible','off')
            set(gca,'xtick',[])
            
            pbaspect([max_x max_y 1])
            saveas(h,'panel_celulas_imagen.png','png') %Sobreescribe el archivo para que cada vez que se cambia la configuración se pueda actualizar 


            %Carga la imagen por pantalla
            app.Image.Enable = true;
            app.Image.Visible = 'on';
            img0 = imread('panel_celulas_imagen.png');
            app.Image.ImageSource = img0;
            
            app.FiguraRepresentacindelasclulasdecadapanelLabel.Visible = 'on';


        end

        % Value changed function: 
        % MostrarinformacinconexioneselctricasCheckBox
        function MostrarinformacinconexioneselctricasCheckBoxValueChanged(app, event)
            value = app.MostrarinformacinconexioneselctricasCheckBox.Value;
            
            %ACTIVA O DESACTIVA EL CUADRO INFORMATIVO DE LAS CONEXIONES
            %ELÉCTRICAS EN FUNCIÓN DEL TIPO DE MATERIAL 
             switch value
                
                case 0
                    
                    app.ConexioneselctricaspaneldealuminioLabel.Visible = 'off';
                    app.ConexioneselctricaspaneldealuminioLabel_2.Visible = 'off';
                    app.ConexioneselctricaspaneldematerialcompuestoLabel.Visible = 'off';
                    app.ClulasenunamismacolumanaserieLabel.Visible = 'off';
                     
                    
                case 1
                    
                    
                    if (app.AluminioENAW7075CheckBox.Value)
                        
                       app.ConexioneselctricaspaneldealuminioLabel.Visible = 'on';
                       app.ConexioneselctricaspaneldealuminioLabel_2.Visible = 'on';
                       app.ConexioneselctricaspaneldematerialcompuestoLabel.Visible = 'off';
                       app.ClulasenunamismacolumanaserieLabel.Visible = 'off';


                    elseif (app.MaterialCompuestoCheckBox.Value)
                        
                       app.ConexioneselctricaspaneldematerialcompuestoLabel.Visible = 'on';
                       app.ClulasenunamismacolumanaserieLabel.Visible = 'on';
                       app.ConexioneselctricaspaneldealuminioLabel.Visible = 'off';
                       app.ConexioneselctricaspaneldealuminioLabel_2.Visible = 'off';
                       
                    else

                     
                    end


            end
        end

        % Value changed function: SCheckBox
        function SCheckBoxValueChanged(app, event)
            value = app.SCheckBox.Value;
            
            %Boton de desplegado _Sí
            
            
            switch value
                
                case 0
                    app.Imagenodesplegado.Visible = 'on';
                    app.Imagedesplegado.Visible = 'off';
                 
                    
                case 1
                    app.Imagenodesplegado.Visible = 'off';
                    app.Imagedesplegado.Visible = 'on';

                    
            end
            
            if (app.SCheckBox.Value)
                
                app.NoCheckBox.Value = 0;
                app.PanelesOrientadosButtonGroup.Visible = 'on';
                
            else
                
                app.NoCheckBox.Value = 1;
                
            end
            
            
        end

        % Value changed function: NoCheckBox
        function NoCheckBoxValueChanged(app, event)
            value = app.NoCheckBox.Value;
            
            %Botón desplegado-NO
            
            
                  switch value
                
                case 0
                    app.Imagenodesplegado.Visible = 'off';
                    app.Imagedesplegado.Visible = 'on';
                 
                    
                case 1
                    app.Imagenodesplegado.Visible = 'on';
                    app.Imagedesplegado.Visible = 'off ';

                    
                  end
                  
                   if (app.NoCheckBox.Value)
                     
                        app.SCheckBox.Value = 0;
                        app.SCheckBox_2.Value = 0;
                        app.NoCheckBox_2.Value = 1;
                        app.PanelesOrientadosButtonGroup.Visible = 'off';                  
                        app.angulodeorientacinEditField.Visible = 'off';
                        app.ngulodeorientacinLabel.Visible = 'off';


                   else 
                       
                       app.SCheckBox.Value = 1;
                
                   end
            
        end

        % Value changed function: SCheckBox_2
        function SCheckBox_2ValueChanged(app, event)
            value = app.SCheckBox_2.Value;
            
            %Botón de orientado-Sí
            
             switch value
                
                case 0
                   
                    app.angulodeorientacinEditField.Visible = 'off';
                    app.ngulodeorientacinLabel.Visible = 'off';


                case 1
                   
                    app.angulodeorientacinEditField.Visible = 'on';
                    app.ngulodeorientacinLabel.Visible = 'on';


            end
            
            if (app.SCheckBox_2.Value)
                
                app.NoCheckBox_2.Value = 0;
                
            else
                
                app.NoCheckBox_2.Value = 1;
                
            end
            
        end

        % Value changed function: NoCheckBox_2
        function NoCheckBox_2ValueChanged(app, event)
            value = app.NoCheckBox_2.Value;
            
            %Botón de orientado -NO
            
               
             switch value
                
                case 0
                  
                    app.angulodeorientacinEditField.Visible = 'on';
                    app.ngulodeorientacinLabel.Visible = 'on';


                case 1
                
                    app.angulodeorientacinEditField.Visible = 'off';
                    app.ngulodeorientacinLabel.Visible = 'off';


            end
            
            if (app.NoCheckBox_2.Value)
                
                app.SCheckBox_2.Value = 0;
            else
                app.SCheckBox_2.Value = 1;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            YN = uiconfirm(app.UIFigure,'Asegúrese de guardar los cambios antes de cerrar. ¿Quiere cerrar la aplicación?', 'Close request');
               if strcmpi(YN,'OK')
                      delete(app)
               end
        end

        % Button pushed function: GuardarcambiosButton
        function GuardarcambiosButtonPushed(app, event)
            
            
            %VUELCA TODOS LOS VALORES SELECCIONADOS EN EL EXCELL.
            
            %%MATERIAL
            nucleo='5056';
            espesor_al= 1.5 %En caso de que se señeccion el de compuesto;
            espesor_comp=4.5 %En casao de que seleccione el aluminio;
            material_usado='no_seleccionado';
            num_panel_desplegados = app.NmerodepanelesdesplegadosporladoSpinner.Value;
            angulo=0; %Si no se modifica el valor inical del ángulo
            
            Long_p = app.EditField.Value*(100+app.f_lbSlider.Value)/100;
            Alt_p = app.EditField_2.Value*(100+app.f_lhSlider.Value)/100;


              %Geometría del satélite
              
              if (app.ButtonGroup.SelectedObject == app.HexagonalButton_2)
                  
                 geometria = 'Hexagono';
              else
                  geometria = 'Rectángulo';
              end
              
              %Material
            
            
              if (app.MaterialCompuestoCheckBox.Value)
                  material_usado = 'Compuesto';
                  espesor_comp = app.EspesorncleommSpinner.Value;
                  
                  if (app.EspesorButtonGroup.SelectedObject == app.PolimeroHRH10Button)
                      
                 
                         nucleo = 'HRH10';
                         
                  elseif (app.EspesorButtonGroup.SelectedObject == app.SandwichAluminio5052Button)
                      
                         nucleo = '5052';
                         
                  else
                      
                         nucleo = '5056';
                  end
                  
                  N_columnas_matcom = app.NmerodecolumnasSpinner_2.Value;
                  N_cupones_matcom = app.NmerodecuponesporcolumnaSpinner_2.Value;
                  N_cell_cupon_matcom= app.NmerodeclulasporcupnSpinner_2.Value;
                  
                  N_columnas_aluminio = app.NmerodecolumnasSpinner_2.Value;
                  N_cell_cupon_aluminio = app.NmerodeclulasporcupnSpinner_2.Value;
                  N_cupones_aluminio = app.NmerodecuponesporcolumnaSpinner_2.Value;


                %Cálculo de la longitud mínima del panel para el panel de
                %material compuesto:
                
                %Margenes compuesto  
                  Offset_Y_comp = 5;
                  
                  Offset_X_comp = 12;
    
                 % Bisagra
                  Hinge_Dim_X_comp = 9;

                 % Dimensiones de un cupón
                  Cell_Dim_X_comp = 80;    % mm

                  Cell_Dim_Y_comp =  40; %mm
                  
                  Panel_Dim_X_comp = N_columnas_matcom*Cell_Dim_X_comp + 2*Hinge_Dim_X_comp + ...
                                      2*Offset_X_comp + (N_columnas_matcom-1)*1;  %L2
                  
                  Panel_Dim_Y_comp = N_cupones_matcom*N_cell_cupon_matcom*Cell_Dim_Y_comp  + ...
                                     2*Offset_Y_comp + N_cupones_matcom*(N_cell_cupon_matcom-1)*1 ...
                                      + (N_cupones_matcom-1)*4;   %L1
                                  
                                  
              %Cálculo de la longitud mínima del panel para el panel de
              %aluminio:
                  
                % Margenes entre cupon y borde del panel
                Offset_X_al = 1;
                Offset_Y_al = 1;
                  
                % Dimensiones de un cupon
                Cupon_Dim_X_al = 84;    % mm
                Cupon_Dim_Y_al =  (N_cell_cupon_aluminio - 1)*41 + 52;
                
                % Bisagra
                Hinge_Dim_X_al = 2*9;

                
                % Dimensiones finales del panel
                Panel_Dim_X_al = N_columnas_aluminio*Cupon_Dim_X_al + 1*(N_columnas_aluminio - 1)...
                             + 2*Offset_X_al + 2*Hinge_Dim_X_al;
                Panel_Dim_Y_al = N_cupones_aluminio*Cupon_Dim_Y_al + 1*(N_cupones_aluminio - 1)...
                             + 2*Offset_Y_al;
                    
                  
              elseif (app.AluminioENAW7075CheckBox.Value)
                  
                  material_usado = 'Aluminium';
                  
                  if (app.EspesorButtonGroup.SelectedObject == app.mmButton)
                         espesor_al = 1.5;
                  elseif (app.EspesorButtonGroup.SelectedObject == app.mmButton_2)
                         espesor_al = 2.5;
                         
                  else 
                      espesor_al = 'campo vacio';
                  end
                  
                  N_columnas_aluminio = app.NmerodecolumnasSpinner.Value;
                  N_cell_cupon_aluminio = app.NmerodeclulasporcupnSpinner.Value;
                  N_cupones_aluminio = app.NmerodecuponesporcolumnaSpinner.Value;
                  
                  N_columnas_matcom = app.NmerodecolumnasSpinner.Value;
                  N_cupones_matcom = app.NmerodecuponesporcolumnaSpinner.Value;
                  N_cell_cupon_matcom= app.NmerodeclulasporcupnSpinner.Value;


              %Cálculo de la longitud mínima del panel para el panel de
              %aluminio:
                  
                % Margenes entre cupon y borde del panel
                Offset_X_al = 1;
                Offset_Y_al = 1;
                  
                % Dimensiones de un cupon
                Cupon_Dim_X_al = 84;    % mm
                Cupon_Dim_Y_al =  (N_cell_cupon_aluminio - 1)*41 + 52;
                
                % Bisagra
                Hinge_Dim_X_al = 2*9;

                
                % Dimensiones finales del panel
                Panel_Dim_X_al = N_columnas_aluminio*Cupon_Dim_X_al + 1*(N_columnas_aluminio - 1)...
                             + 2*Offset_X_al + 2*Hinge_Dim_X_al;
                Panel_Dim_Y_al = N_cupones_aluminio*Cupon_Dim_Y_al + 1*(N_cupones_aluminio - 1)...
                             + 2*Offset_Y_al;
             
                   %Cálculo de la longitud mínima del panel para el panel de
                %material compuesto:
                
                %Margenes compuesto  
                  Offset_Y_comp = 5;
                  
                  Offset_X_comp = 12;
    
                 % Bisagra
                  Hinge_Dim_X_comp = 9;

                 % Dimensiones de un cupón
                  Cell_Dim_X_comp = 80;    % mm

                  Cell_Dim_Y_comp =  40; %mm
                  
                  Panel_Dim_X_comp = N_columnas_matcom*Cell_Dim_X_comp + 2*Hinge_Dim_X_comp + ...
                                      2*Offset_X_comp + (N_columnas_matcom-1)*1;  %L2
                  
                  Panel_Dim_Y_comp = N_cupones_matcom*N_cell_cupon_matcom*Cell_Dim_Y_comp  + ...
                                     2*Offset_Y_comp + N_cupones_matcom*(N_cell_cupon_matcom-1)*1 ...
                                      + (N_cupones_matcom-1)*4;   %L1
                  
              end
              
            %% CONFIGURACIÓN DESPLIEGUE Y ORIENTACIÓN %%
            
            if (app.SCheckBox.Value)
                
                despliegue = 'OPEN';
                     
                if (app.SCheckBox_2.Value)
                   
                    orientable = 'OPEN';
                    angulo = app.angulodeorientacinEditField.Value;
                    
                elseif (app.NoCheckBox_2.Value)
                    
                    orientable = 'CLOSED';
                    
                else
                    
                    orientable = 'No seleccionado';
                    
                end
                
                
            elseif (app.NoCheckBox.Value)
                
              despliegue = 'CLOSED';
              angulo = 0;
              orientable = 'CLOSED';
      
               
            end
            
            param = [{material_usado},{espesor_al},{nucleo},{geometria},{Long_p}, {Alt_p},...
                 {N_cell_cupon_matcom}, {N_cupones_matcom}, {N_columnas_matcom},{Panel_Dim_X_comp},{Panel_Dim_Y_comp},...
                 {N_columnas_aluminio}, {N_cell_cupon_aluminio}, {N_cupones_aluminio},{Panel_Dim_X_al},{Panel_Dim_Y_al},...
                 {num_panel_desplegados},{despliegue},{orientable},{angulo}];
             
            param2 = [{material_usado},{Panel_Dim_X_al},{Panel_Dim_Y_al},{Panel_Dim_X_comp},{Panel_Dim_Y_comp},{N_cell_cupon_matcom},...
                {N_cupones_matcom},{N_columnas_matcom}, {num_panel_desplegados},{espesor_al},{espesor_comp},{nucleo},...
                {despliegue}, {orientable},{angulo}];
            
            %comprobar que va 
        
            xlswrite('..\40\400_Parameters.xls',param2,1,'A2');
        end

        % Value changing function: NmerodeclulasporcupnSpinner
        function NmerodeclulasporcupnSpinnerValueChanging(app, event)
            
            %ALUMINIO


            Cupon_Cells = event.Value;
           
            
            %Establecer límite para el número de cupones, una vez definidas
            %el número de células por cupón
             % Margenes entre cupon y borde del panel
            Offset_X = 1;
            Offset_Y = 1;
            
            %Dimensiones del cupón
            Cupon_Dim_X = 84;    % mm
            
            %Longitudes
            Long_p = app.EditField.Value*(100+app.f_lbSlider.Value)/100;
            Alt_p = app.EditField_2.Value*(100+app.f_lhSlider.Value)/100;
            
            
            if Cupon_Cells ==2
          
                Cupon_Dim_Y =  (Cupon_Cells - 1)*41 + 52;
                max_Cupon_N_Y = floor((Alt_p + 1 - 2*Offset_Y)/(Cupon_Dim_Y+1));
                
                if max_Cupon_N_Y ==3
                    
                    app.NmerodecuponesporcolumnaSpinner.Limits=[3  3.1];
                    app.NmerodecuponesporcolumnaSpinner.Value =3;
                    
                else
                    app.NmerodecuponesporcolumnaSpinner.Limits=[3  max_Cupon_N_Y];
                end

            else
                
                Cupon_Dim_Y =  (Cupon_Cells - 1)*41 + 52;
                max_Cupon_N_Y = floor((Alt_p + 1 - 2*Offset_Y)/(Cupon_Dim_Y+1));
                
                while max_Cupon_N_Y < 2
                    
                     Cupon_Cells = Cupon_Cells-1;
                     Cupon_Dim_Y =  (Cupon_Cells - 1)*41 + 52;
                     max_Cupon_N_Y = floor((Alt_p + 1 - 2*Offset_Y)/(Cupon_Dim_Y+1));
                    
                end
                
                if max_Cupon_N_Y == 2
   
                    Cupon_Dim_Y =  (Cupon_Cells - 1)*41 + 52;
                    max_Cupon_N_Y = floor((Alt_p + 1 - 2*Offset_Y)/(Cupon_Dim_Y+1));
                    app.NmerodecuponesporcolumnaSpinner.Limits=[2  2.1];
                    app.NmerodecuponesporcolumnaSpinner.Value=2;
                    
                else
                    Cupon_Dim_Y =  (Cupon_Cells - 1)*41 + 52;
                    max_Cupon_N_Y = floor((Alt_p + 1 - 2*Offset_Y)/(Cupon_Dim_Y+1));
                    app.NmerodecuponesporcolumnaSpinner.Limits=[2  max_Cupon_N_Y];
                    
                end


            end


        end

        % Value changing function: NmerodecuponesporcolumnaSpinner
        function NmerodecuponesporcolumnaSpinnerValueChanging(app, event)

            %ALUMINIO
            Numero_Cupones= event.Value;


           %Establecer límite para el número de cupones, una vez definidas
            %el número de células por cupón
             % Margenes entre cupon y borde del panel
            Offset_X = 1;
            Offset_Y = 1;
            
            %Dimensiones del cupón
            Cupon_Dim_X = 84;    % mm
            
            %Longitudes
            Long_p = app.EditField.Value*(100+app.f_lbSlider.Value)/100;
            Alt_p = app.EditField_2.Value*(100+app.f_lhSlider.Value)/100;
            
            max_Cupon_Cells = floor((((((Alt_p + 1 - 2*Offset_Y)/Numero_Cupones)-1)-52)/41)+1);
            
            
            if max_Cupon_Cells <= 2
                app.NmerodeclulasporcupnSpinner.Limits=[2 2.1];
                app.NmerodeclulasporcupnSpinner.Value=2;
                
            else
                app.NmerodeclulasporcupnSpinner.Limits=[2 max_Cupon_Cells];
                
            end
        end

        % Value changing function: NmerodecolumnasSpinner
        function NmerodecolumnasSpinnerValueChanging(app, event)
           
           %ALUMINIO
            % Bisagra
            Hinge_Dim_X = 2*9;
            
            % Margenes entre cupon y borde del panel
            Offset_X = 1;
            Offset_Y = 1;
            
             % Dimensiones de un cupon
            Cupon_Dim_X = 84;    % mm
            
            %Obtención del número máximo de colummas que caben en función
            %de la longitud del panel.
            Long_p = app.EditField.Value*(100 + app.f_lbSlider.Value)/100;
            
            max_Cupon_N_X = floor((Long_p + 1-2*Hinge_Dim_X-2*Offset_X)/(Cupon_Dim_X+1));
            
            app.NmerodecolumnasSpinner.Limits=[2 max_Cupon_N_X];


        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
%            
%             %ALUMINIO 
%             
%               
%             % Bisagra
%             Hinge_Dim_X = 2*9;
%             
%             % Margenes entre cupon y borde del panel
%             Offset_X = 1;
%             Offset_Y = 1;
%             
%              % Dimensiones de un cupon
%             Cupon_Dim_X = 84;    % mm
%             
%             %Obtención del número máximo de colummas que caben en función
%             %de la longitud del panel.
%             Long_p = app.EditField.Value*(100 + app.f_lbSlider.Value)/100;
%             
%             max_Cupon_N_X = floor((Long_p + 1-2*Hinge_Dim_X-2*Offset_X)/(Cupon_Dim_X+1));
%             
%             app.NmerodecolumnasSpinner.Limits=[1 max_Cupon_N_X];
%             
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
% 
%             %ALUMINIO
%             
%             
%             Alt_p = app.EditField_2.Value*(100+app.f_lhSlider.Value)/100;
%             
%               % Margenes entre cupon y borde del panel
%             Offset_X = 1;
%             Offset_Y = 1;
%             
%             %Cálculo del número máximo de células que cabrian en un cupón
%             %para una determinada medida
%             
%             max_Cupon_Cells = floor((Alt_p + 41 -52 -2*Offset_Y)/41);
%             app.NmerodeclulasporcupnSpinner.Limits=[1 max_Cupon_Cells];
            
        end

        % Value changing function: NmerodeclulasporcupnSpinner_2
        function NmerodeclulasporcupnSpinner_2ValueChanging(app, event)
%             changingValue = event.Value;
            
                    %MATERIAL COMPUESTO 
            
            Cupon_Cells = event.Value;
            
            %Establecer límite para el número de cupones, una vez definidas
            %el número de células por cupón
             % Margenes entre cupon y borde del panel
            Offset_Y = 5;
            
            %Dimensiones del cupón

            Cell_Dim_Y =  40; %mm
            
            Alt_p = app.EditField_2.Value*(100+app.f_lhSlider.Value)/100;
            
%             max_Cupon_N_Y = floor((Alt_p - 2*Offset_Y+4)/(Cell_Dim_Y*Cupon_Cells+(Cupon_Cells-1)*1+4));
            
            
            if Cupon_Cells ==2
          
              
                max_Cupon_N_Y = floor((Alt_p - 2*Offset_Y+4)/(Cell_Dim_Y*Cupon_Cells+(Cupon_Cells-1)*1+4));
                
                if max_Cupon_N_Y ==3
                    
                    app.NmerodecuponesporcolumnaSpinner_2.Limits=[3  3.1];
                    app.NmerodecuponesporcolumnaSpinner_2.Value =3;
                    
                else
                    app.NmerodecuponesporcolumnaSpinner_2.Limits=[3  max_Cupon_N_Y];
                end

            else
                
              
                max_Cupon_N_Y = floor((Alt_p - 2*Offset_Y+4)/(Cell_Dim_Y*Cupon_Cells+(Cupon_Cells-1)*1+4));
                
                while max_Cupon_N_Y < 2
                    
                     Cupon_Cells = Cupon_Cells-1;
                   
                     max_Cupon_N_Y = floor((Alt_p - 2*Offset_Y+4)/(Cell_Dim_Y*Cupon_Cells+(Cupon_Cells-1)*1+4));
                    
                end
                
                if max_Cupon_N_Y == 2
   
                    
                    max_Cupon_N_Y = floor((Alt_p - 2*Offset_Y+4)/(Cell_Dim_Y*Cupon_Cells+(Cupon_Cells-1)*1+4));
                    app.NmerodecuponesporcolumnaSpinner_2.Limits=[2  2.1];
                    app.NmerodecuponesporcolumnaSpinner_2.Value=2;
                    
                else
                    
                    max_Cupon_N_Y = floor((Alt_p - 2*Offset_Y+4)/(Cell_Dim_Y*Cupon_Cells+(Cupon_Cells-1)*1+4));
                    app.NmerodecuponesporcolumnaSpinner_2.Limits=[2  max_Cupon_N_Y];
                    
                end

            end


        end

        % Value changing function: NmerodecuponesporcolumnaSpinner_2
        function NmerodecuponesporcolumnaSpinner_2ValueChanging(app, event)
            changingValue = event.Value;
            
            %COMPUESTO
            Numero_Cupones= event.Value;


           %Establecer límite para el número de cupones, una vez definidas
            %el número de células por cupón
             % Margenes entre cupon y borde del panel
            Offset_X = 12;
            Offset_Y = 5;
            
            %Dimensiones del cupón
            Cupon_Dim_X = 80;    % mm
            Cell_Dim_Y =  40; %mm
            
            %Longitudes
            Long_p = app.EditField.Value*(100+app.f_lbSlider.Value)/100;
            Alt_p = app.EditField_2.Value*(100+app.f_lhSlider.Value)/100;


            max_Cupon_Cells = floor(((Alt_p-2*Offset_Y+4)/Numero_Cupones +4)/(Cell_Dim_Y +4));
            
            if max_Cupon_Cells <= 2
                app.NmerodeclulasporcupnSpinner_2.Limits=[2 2.1];
                app.NmerodeclulasporcupnSpinner_2.Value=2;
                
            else
                app.NmerodeclulasporcupnSpinner_2.Limits=[2 max_Cupon_Cells];
                
            end


        end

        % Value changed function: NmerodeclulasporcupnSpinner_2
        function NmerodeclulasporcupnSpinner_2ValueChanged(app, event)
           
                  %MATERIAL COMPUESTO
            
            
%             Alt_p = app.EditField_2.Value*(100+app.f_lhSlider.Value)/100;
%             
%               % Margenes entre cupon
%             Offset_Y = 5;
%             
%             %Cálculo del número máximo de células que cabrian en un cupón
%             %para una determinada medida
%             
%             max_Cupon_Cells = floor((Alt_p -2*Offset_Y + 1)/(40+1));
%             app.NmerodeclulasporcupnSpinner_2.Limits=[2 max_Cupon_Cells];
            
        end

        % Value changing function: NmerodecolumnasSpinner_2
        function NmerodecolumnasSpinner_2ValueChanging(app, event)


            %MATERIAL COMPUESTO
                     
            % Bisagra
            Hinge_Dim_X = 9;
            
            % Margenes entre cupon y borde del panel
            Offset_X = 12;
           
             % Dimensiones de un cupon
            Cell_Dim_X = 80;    % mm
            
            %Obtención del número máximo de colummas que caben en función
            %de la longitud del panel.
            Long_p = app.EditField.Value*(100 + app.f_lbSlider.Value)/100;
            
            max_Cupon_N_X = floor((Long_p + 1-2*Hinge_Dim_X-2*Offset_X)/(Cell_Dim_X+1));
            
            app.NmerodecolumnasSpinner_2.Limits=[2 max_Cupon_N_X];


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeometrayMaterialTab = uitab(app.TabGroup);
            app.GeometrayMaterialTab.Title = 'Geometría y Material';
            app.PANELESSOLARESLabel = uilabel(app.GeometrayMaterialTab);
            app.PANELESSOLARESLabel.Text = 'PANELES SOLARES';
            app.MaterialButtonGroup = uibuttongroup(app.GeometrayMaterialTab);
            app.MaterialButtonGroup.Title = 'Material';
            app.EspesorButtonGroup = uibuttongroup(app.MaterialButtonGroup);
            app.EspesorButtonGroup.Title = 'Espesor';
            app.mmButton = uiradiobutton(app.EspesorButtonGroup);
            app.mmButton.Text = '  1,5 mm';
            app.mmButton.Value = true;
            app.mmButton_2 = uiradiobutton(app.EspesorButtonGroup);
            app.mmButton_2.Text = '  2,5 mm';
            app.AluminioENAW7075CheckBox = uicheckbox(app.MaterialButtonGroup);
            app.AluminioENAW7075CheckBox.ValueChangedFcn = createCallbackFcn(app, @AluminioENAW7075CheckBoxValueChanged, true);
            app.AluminioENAW7075CheckBox.Text = 'Aluminio (EN AW 7075)';
            app.AluminioENAW7075CheckBox.Value = true;
            app.MaterialCompuestoCheckBox = uicheckbox(app.MaterialButtonGroup);
            app.MaterialCompuestoCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaterialCompuestoCheckBoxValueChanged, true);
            app.MaterialCompuestoCheckBox.Text = 'Material Compuesto';
            app.NcleoButtonGroup = uibuttongroup(app.MaterialButtonGroup);
            app.NcleoButtonGroup.Title = 'Núcleo';
            app.NcleoButtonGroup.Visible = 'off';
            app.PolimeroHRH10Button = uiradiobutton(app.NcleoButtonGroup);
            app.PolimeroHRH10Button.Text = 'Polimero [HRH-10]';
            app.PolimeroHRH10Button.Value = true;
            app.SandwichAluminio5052Button = uiradiobutton(app.NcleoButtonGroup);
            app.SandwichAluminio5052Button.Text = ' Sandwich Aluminio [5052]';
            app.SandwichAlumnio5056Button = uiradiobutton(app.NcleoButtonGroup);
            app.SandwichAlumnio5056Button.Text = ' Sandwich Alumnio [5056]';
            app.EspesorncleommSpinnerLabel = uilabel(app.NcleoButtonGroup);
            app.EspesorncleommSpinnerLabel.Text = 'Espesor núcleo [mm] ';
            app.EspesorncleommSpinner = uispinner(app.NcleoButtonGroup);
            app.EspesorncleommSpinner.Step = 0.5;
            app.EspesorncleommSpinner.Limits = [4.5 5];
            app.EspesorncleommSpinner.Value = 4.5;
            app.Fibra914T300ButtonGroup = uibuttongroup(app.MaterialButtonGroup);
            app.Fibra914T300ButtonGroup.Title = 'Fibra [914-T300]';
            app.Fibra914T300ButtonGroup.Visible = 'off';
            app.Paratodosloscasoselespesordelafibraser05mmLabel = uilabel(app.Fibra914T300ButtonGroup);
            app.Paratodosloscasoselespesordelafibraser05mmLabel.Text = {'Para todos los'; ' casos el espesor'; 'de la fibra será'; '0.5 mm.'};
            app.GeometraSatlitePanel = uipanel(app.GeometrayMaterialTab);
            app.GeometraSatlitePanel.Title = 'Geometría Satélite';
            app.LongitudbasedelsatlitelbmmLabel = uilabel(app.GeometraSatlitePanel);
            app.LongitudbasedelsatlitelbmmLabel.Text = 'Longitud base del satélite (lb) [mm]';
            app.EditField = uieditfield(app.GeometraSatlitePanel, 'numeric');
            app.EditField.Limits = [300 400];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 400;
            app.LongitudalturadelsatlitelhmmLabel = uilabel(app.GeometraSatlitePanel);
            app.LongitudalturadelsatlitelhmmLabel.Text = 'Longitud altura del satélite (lh) [mm]';
            app.EditField_2 = uieditfield(app.GeometraSatlitePanel, 'numeric');
            app.EditField_2.Limits = [300 450];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 450;
            app.f_lbLabel = uilabel(app.GeometraSatlitePanel);
            app.f_lbLabel.Text = 'f_lb [%]';
            app.f_lbSlider = uislider(app.GeometraSatlitePanel);
            app.f_lbSlider.Limits = [0 10];
            app.f_lhLabel = uilabel(app.GeometraSatlitePanel);
            app.f_lhLabel.Text = 'f_lh [%]';
            app.f_lhSlider = uislider(app.GeometraSatlitePanel);
            app.f_lhSlider.Limits = [0 10];
            app.ButtonGroup = uibuttongroup(app.GeometraSatlitePanel);
            app.HexagonalButton_2 = uiradiobutton(app.ButtonGroup);
            app.HexagonalButton_2.Text = 'Hexagonal ';
            app.RectangularButton_2 = uiradiobutton(app.ButtonGroup);
            app.RectangularButton_2.Text = 'Rectangular';
            app.RectangularButton_2.Value = true;
            app.SobredimensinmximapermitidaLabel = uilabel(app.GeometraSatlitePanel);
            app.SobredimensinmximapermitidaLabel.Text = 'Sobredimensión máxima permitida';
            app.PotenciaTab = uitab(app.TabGroup);
            app.PotenciaTab.Title = 'Potencia';
            app.HTML = uihtml(app.PotenciaTab);
            app.HTML.HTMLSource = 'Nota:El valor de la potencia en función de la configuración elegida se ha calculado para  T = 28ºC, un ángulo incidente del sol con respcto al panel de 0º y para  C =1367 W/m2.';
            app.HTML2 = uihtml(app.PotenciaTab);
            app.HTML2.HTMLSource = 'Carácterísticas células solares:  Vmp = 2.409 [V]  Imp =  0.5029 [A]';
            app.ConfiguracinclulassolaresencadapanelButtonGroup = uibuttongroup(app.PotenciaTab);
            app.ConfiguracinclulassolaresencadapanelButtonGroup.Title = 'Configuración células solares en cada panel (Elegir antes el material)';
            app.ClculodePotenciaButton = uibutton(app.ConfiguracinclulassolaresencadapanelButtonGroup, 'push');
            app.ClculodePotenciaButton.ButtonPushedFcn = createCallbackFcn(app, @ClculodePotenciaButtonPushed, true);
            app.ClculodePotenciaButton.Text = 'Cálculo de Potencia';
            app.MaterialCompuestoPanel = uipanel(app.ConfiguracinclulassolaresencadapanelButtonGroup);
            app.MaterialCompuestoPanel.Title = 'Material Compuesto';
            app.NmerodecuponesporcolumnaSpinner_2Label = uilabel(app.MaterialCompuestoPanel);
            app.NmerodecuponesporcolumnaSpinner_2Label.Text = {'Número de cupones '; 'por columna'};
            app.NmerodecuponesporcolumnaSpinner_2 = uispinner(app.MaterialCompuestoPanel);
            app.NmerodecuponesporcolumnaSpinner_2.ValueChangingFcn = createCallbackFcn(app, @NmerodecuponesporcolumnaSpinner_2ValueChanging, true);
            app.NmerodecuponesporcolumnaSpinner_2.Limits = [3 4];
            app.NmerodecuponesporcolumnaSpinner_2.ValueDisplayFormat = '%.0f';
            app.NmerodecuponesporcolumnaSpinner_2.Value = 3;
            app.NmerodeclulasporcupnSpinner_2 = uispinner(app.MaterialCompuestoPanel);
            app.NmerodeclulasporcupnSpinner_2.ValueChangingFcn = createCallbackFcn(app, @NmerodeclulasporcupnSpinner_2ValueChanging, true);
            app.NmerodeclulasporcupnSpinner_2.Limits = [2 3];
            app.NmerodeclulasporcupnSpinner_2.ValueDisplayFormat = '%.0f';
            app.NmerodeclulasporcupnSpinner_2.ValueChangedFcn = createCallbackFcn(app, @NmerodeclulasporcupnSpinner_2ValueChanged, true);
            app.NmerodeclulasporcupnSpinner_2.Value = 2;
            app.NmerodeclulasporcupnSpinner_2Label = uilabel(app.MaterialCompuestoPanel);
            app.NmerodeclulasporcupnSpinner_2Label.Text = {'Número de células '; 'por cupón'};
            app.NmerodecolumnasSpinner_2Label = uilabel(app.MaterialCompuestoPanel);
            app.NmerodecolumnasSpinner_2Label.Text = 'Número de columnas';
            app.NmerodecolumnasSpinner_2 = uispinner(app.MaterialCompuestoPanel);
            app.NmerodecolumnasSpinner_2.ValueChangingFcn = createCallbackFcn(app, @NmerodecolumnasSpinner_2ValueChanging, true);
            app.NmerodecolumnasSpinner_2.Limits = [2 10];
            app.NmerodecolumnasSpinner_2.ValueDisplayFormat = '%.0f';
            app.NmerodecolumnasSpinner_2.Value = 3;
            app.NmerodepanelesdesplegadosporladoLabel_2 = uilabel(app.ConfiguracinclulassolaresencadapanelButtonGroup);
            app.NmerodepanelesdesplegadosporladoLabel_2.Text = {'Número de paneles '; 'desplegados por lado'};
            app.NmerodepanelesdesplegadosporladoSpinner = uispinner(app.ConfiguracinclulassolaresencadapanelButtonGroup);
            app.NmerodepanelesdesplegadosporladoSpinner.Limits = [1 5];
            app.NmerodepanelesdesplegadosporladoSpinner.Value = 1;
            app.AlumnioPanel = uipanel(app.ConfiguracinclulassolaresencadapanelButtonGroup);
            app.AlumnioPanel.Title = 'Alumnio';
            app.NmerodecuponesporcolumnaLabel_2 = uilabel(app.AlumnioPanel);
            app.NmerodecuponesporcolumnaLabel_2.Text = {'Número de cupones'; ' por columna'};
            app.NmerodecuponesporcolumnaSpinner = uispinner(app.AlumnioPanel);
            app.NmerodecuponesporcolumnaSpinner.ValueChangingFcn = createCallbackFcn(app, @NmerodecuponesporcolumnaSpinnerValueChanging, true);
            app.NmerodecuponesporcolumnaSpinner.Limits = [3 4];
            app.NmerodecuponesporcolumnaSpinner.ValueDisplayFormat = '%.0f';
            app.NmerodecuponesporcolumnaSpinner.Value = 3;
            app.NmerodecolumnasSpinnerLabel = uilabel(app.AlumnioPanel);
            app.NmerodecolumnasSpinnerLabel.Text = 'Número de columnas';
            app.NmerodecolumnasSpinner = uispinner(app.AlumnioPanel);
            app.NmerodecolumnasSpinner.ValueChangingFcn = createCallbackFcn(app, @NmerodecolumnasSpinnerValueChanging, true);
            app.NmerodecolumnasSpinner.Limits = [2 10];
            app.NmerodecolumnasSpinner.ValueDisplayFormat = '%.0f';
            app.NmerodecolumnasSpinner.Value = 3;
            app.NmerodeclulasporcupnLabel_2 = uilabel(app.AlumnioPanel);
            app.NmerodeclulasporcupnLabel_2.Text = {'Número de células '; 'por cupón'};
            app.NmerodeclulasporcupnSpinner = uispinner(app.AlumnioPanel);
            app.NmerodeclulasporcupnSpinner.ValueChangingFcn = createCallbackFcn(app, @NmerodeclulasporcupnSpinnerValueChanging, true);
            app.NmerodeclulasporcupnSpinner.Limits = [2 3];
            app.NmerodeclulasporcupnSpinner.ValueDisplayFormat = '%.0f';
            app.NmerodeclulasporcupnSpinner.Value = 2;
            app.PotenciaWTextAreaLabel = uilabel(app.PotenciaTab);
            app.PotenciaWTextAreaLabel.Text = 'Potencia [W]';
            app.PotenciaWTextArea = uitextarea(app.PotenciaTab);
            app.PotenciaWTextArea.Editable = 'off';
            app.Image = uiimage(app.PotenciaTab);
            app.Image.Visible = 'off';
            app.ConexioneselctricaspaneldealuminioLabel = uilabel(app.PotenciaTab);
            app.ConexioneselctricaspaneldealuminioLabel.Visible = 'off';
            app.ConexioneselctricaspaneldealuminioLabel.Text = {'Células dentro de un mismo cupón : serie.'; 'Entre cupones: paralelo'; 'Entre panles desplegados: paralelo'};
            app.ConexioneselctricaspaneldealuminioLabel_2 = uilabel(app.PotenciaTab);
            app.ConexioneselctricaspaneldealuminioLabel_2.Visible = 'off';
            app.ConexioneselctricaspaneldealuminioLabel_2.Text = 'Conexiones eléctricas panel de aluminio:';
            app.VmpVEditFieldLabel = uilabel(app.PotenciaTab);
            app.VmpVEditFieldLabel.Text = 'Vmp [V]';
            app.VmpVEditField = uieditfield(app.PotenciaTab, 'numeric');
            app.VmpVEditField.Editable = 'off';
            app.ImpAEditFieldLabel = uilabel(app.PotenciaTab);
            app.ImpAEditFieldLabel.Text = 'Imp [A]';
            app.ImpAEditField = uieditfield(app.PotenciaTab, 'numeric');
            app.ImpAEditField.Editable = 'off';
            app.TensineIntensidaddelpanelenelpuntodemximapotenciaT28CLabel = uilabel(app.PotenciaTab);
            app.TensineIntensidaddelpanelenelpuntodemximapotenciaT28CLabel.Text = {' Tensión e Intensidad de cada bloque de paneles '; 'desplegables en el punto de máxima potencia (T = 28ºC)'};
            app.MostrarinformacinconexioneselctricasCheckBox = uicheckbox(app.PotenciaTab);
            app.MostrarinformacinconexioneselctricasCheckBox.ValueChangedFcn = createCallbackFcn(app, @MostrarinformacinconexioneselctricasCheckBoxValueChanged, true);
            app.MostrarinformacinconexioneselctricasCheckBox.Text = 'Mostrar información conexiones eléctricas';
            app.ConexioneselctricaspaneldematerialcompuestoLabel = uilabel(app.PotenciaTab);
            app.ConexioneselctricaspaneldematerialcompuestoLabel.Visible = 'off';
            app.ConexioneselctricaspaneldematerialcompuestoLabel.Text = 'Conexiones eléctricas panel de material compuesto';
            app.ClulasenunamismacolumanaserieLabel = uilabel(app.PotenciaTab);
            app.ClulasenunamismacolumanaserieLabel.Visible = 'off';
            app.ClulasenunamismacolumanaserieLabel.Text = {'Número de células seleccionadas por cupón '; 'determina el número de células conectadas en serie'; ''; 'Entre columnas: paralelo'; 'Entre paneles: paralelo'};
            app.FiguraRepresentacindelasclulasdecadapanelLabel = uilabel(app.PotenciaTab);
            app.FiguraRepresentacindelasclulasdecadapanelLabel.Visible = 'off';
            app.FiguraRepresentacindelasclulasdecadapanelLabel.Text = 'Figura: Representación de las células de cada panel ';
            app.DespliegueyorientacinTab = uitab(app.TabGroup);
            app.DespliegueyorientacinTab.Title = 'Despliegue y orientación';
            app.HTML3 = uihtml(app.DespliegueyorientacinTab);
            app.HTML3.HTMLSource = 'Elegir la configuración en la que se quiere mostrar los paneles:';
            app.PanelesDesplagadosButtonGroup = uibuttongroup(app.DespliegueyorientacinTab);
            app.PanelesDesplagadosButtonGroup.Title = 'Paneles Desplagados';
            app.SCheckBox = uicheckbox(app.PanelesDesplagadosButtonGroup);
            app.SCheckBox.ValueChangedFcn = createCallbackFcn(app, @SCheckBoxValueChanged, true);
            app.SCheckBox.Text = 'Sí';
            app.NoCheckBox = uicheckbox(app.PanelesDesplagadosButtonGroup);
            app.NoCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoCheckBoxValueChanged, true);
            app.NoCheckBox.Text = 'No';
            app.NoCheckBox.Value = true;
            app.ngulodeorientacinLabel = uilabel(app.DespliegueyorientacinTab);
            app.ngulodeorientacinLabel.Visible = 'off';
            app.ngulodeorientacinLabel.Text = 'Ángulo de orientación [º]';
            app.angulodeorientacinEditField = uieditfield(app.DespliegueyorientacinTab, 'numeric');
            app.angulodeorientacinEditField.Limits = [-180 180];
            app.angulodeorientacinEditField.Visible = 'off';
            app.PanelesOrientadosButtonGroup = uibuttongroup(app.DespliegueyorientacinTab);
            app.PanelesOrientadosButtonGroup.Title = 'Paneles Orientados';
            app.PanelesOrientadosButtonGroup.Visible = 'off';
            app.SCheckBox_2 = uicheckbox(app.PanelesOrientadosButtonGroup);
            app.SCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @SCheckBox_2ValueChanged, true);
            app.SCheckBox_2.Text = 'Sí';
            app.NoCheckBox_2 = uicheckbox(app.PanelesOrientadosButtonGroup);
            app.NoCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @NoCheckBox_2ValueChanged, true);
            app.NoCheckBox_2.Text = 'No';
            app.NoCheckBox_2.Value = true;
            app.Imagenodesplegado = uiimage(app.DespliegueyorientacinTab);
            app.Imagenodesplegado.ImageSource = 'Al_Cerrado.png';
            app.Imagedesplegado = uiimage(app.DespliegueyorientacinTab);
            app.Imagedesplegado.Visible = 'off';
            app.Imagedesplegado.ImageSource = 'Al_Abierto.png';
            app.GuardarcambiosButton = uibutton(app.DespliegueyorientacinTab, 'push');
            app.GuardarcambiosButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarcambiosButtonPushed, true);
            app.GuardarcambiosButton.Text = 'Guardar cambios ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_PANELES

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end