classdef gui_pbl_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        GeometrayMaterialTab           matlab.ui.container.Tab
        PANELESSOLARESLabel            matlab.ui.control.Label
        MaterialButtonGroup            matlab.ui.container.ButtonGroup
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        Espesor15mmButton              matlab.ui.control.RadioButton
        Espesor25mmButton              matlab.ui.control.RadioButton
        AluminioCheckBox               matlab.ui.control.CheckBox
        MaterialCompuestoCheckBox      matlab.ui.control.CheckBox
        ButtonGroup_3                  matlab.ui.container.ButtonGroup
        Nucleo1Button                  matlab.ui.control.RadioButton
        Nucleo2Button                  matlab.ui.control.RadioButton
        GeometraSatlitePanel           matlab.ui.container.Panel
        LongitudbasedelsatlitelbmmLabel  matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        LongitudalturadelsatlitelhmmLabel  matlab.ui.control.Label
        EditField_2                    matlab.ui.control.NumericEditField
        f_lbSliderLabel                matlab.ui.control.Label
        f_lbSlider                     matlab.ui.control.Slider
        f_lhSliderLabel                matlab.ui.control.Label
        f_lhSlider                     matlab.ui.control.Slider
        ButtonGroup                    matlab.ui.container.ButtonGroup
        HexagonalButton_2              matlab.ui.control.RadioButton
        RectangularButton_2            matlab.ui.control.RadioButton
        AmpliarmargenlateralpanelesCheckBox  matlab.ui.control.CheckBox
        Image2                         matlab.ui.control.Image
        PotenciaTab                    matlab.ui.container.Tab
        HTML                           matlab.ui.control.HTML
        HTML2                          matlab.ui.control.HTML
        ConfiguracinclulassolaresencadapanelButtonGroup  matlab.ui.container.ButtonGroup
        NmerodecolumnasEditFieldLabel  matlab.ui.control.Label
        NmerodecolumnasEditField       matlab.ui.control.NumericEditField
        NmerodeclulasporcolumnasEditFieldLabel  matlab.ui.control.Label
        NmerodeclulasporcolumnasEditField  matlab.ui.control.NumericEditField
        NmerodepanelesdesplegadosporladoLabel  matlab.ui.control.Label
        NmerodepanelesdesplegadosporladoEditField  matlab.ui.control.NumericEditField
        NmerodeclulasporcupnLabel      matlab.ui.control.Label
        NmerodeclulasporcupnEditField  matlab.ui.control.NumericEditField
        NmerodecuponesporcolumnaLabel  matlab.ui.control.Label
        NmerodecuponesporcolumnaEditField  matlab.ui.control.NumericEditField
        NmerodecolumnasEditField_2Label  matlab.ui.control.Label
        NmerodecolumnasEditField_2     matlab.ui.control.NumericEditField
        ClculodePotenciaButton         matlab.ui.control.Button
        PotenciaWTextAreaLabel         matlab.ui.control.Label
        PotenciaWTextArea              matlab.ui.control.TextArea
        Image                          matlab.ui.control.Image
        ConexioneselctricaspaneldealuminioLabel  matlab.ui.control.Label
        ConexioneselctricaspaneldealuminioLabel_2  matlab.ui.control.Label
        VmpVEditFieldLabel             matlab.ui.control.Label
        VmpVEditField                  matlab.ui.control.NumericEditField
        ImpAEditFieldLabel             matlab.ui.control.Label
        ImpAEditField                  matlab.ui.control.NumericEditField
        TensineIntensidaddelpanelenelpuntodemximapotenciaT28CLabel  matlab.ui.control.Label
        MostrarinformacinconexioneselctricasCheckBox  matlab.ui.control.CheckBox
        ConexioneselctricaspaneldematerialcompuestoLabel  matlab.ui.control.Label
        ClulasenunamismacolumanaserieLabel  matlab.ui.control.Label
        DespliegueyorientacinTab       matlab.ui.container.Tab
        HTML3                          matlab.ui.control.HTML
        PanelesDesplagadosButtonGroup  matlab.ui.container.ButtonGroup
        SCheckBox                      matlab.ui.control.CheckBox
        NoCheckBox                     matlab.ui.control.CheckBox
        ngulodeorientacinEditFieldLabel  matlab.ui.control.Label
        angulodeorientacinEditField    matlab.ui.control.NumericEditField
        PanelesOrientadosButtonGroup   matlab.ui.container.ButtonGroup
        SCheckBox_2                    matlab.ui.control.CheckBox
        NoCheckBox_2                   matlab.ui.control.CheckBox
        Imagenodesplegado              matlab.ui.control.Image
        Imagedesplegado                matlab.ui.control.Image
        Imageorientado                 matlab.ui.control.Image
        GuardarcambiosButton           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AluminioCheckBox
        function AluminioCheckBoxValueChanged(app, event)
            value = app.AluminioCheckBox.Value;

            switch value
                
                case 0
                    %app.MaterialCompuestoCheckBox.Value=1;
                    app.Espesor15mmButton.Visible = 'off';
                    app.Espesor25mmButton.Visible = 'off';
                    app.Nucleo1Button.Visible = 'off';
                    app.Nucleo2Button.Visible = 'off';           
                 
                    
                case 1
                    %app.MaterialCompuestoCheckBox.Value=0;
                    app.Espesor15mmButton.Visible = 'on';
                    app.Espesor25mmButton.Visible = 'on';
                    app.Nucleo1Button.Visible = 'off';
                    app.Nucleo2Button.Visible = 'off';

                    
            end
            
          
            if (app.AluminioCheckBox.Value)
                
                    app.MaterialCompuestoCheckBox.Value=0;
                    app.NmerodeclulasporcolumnasEditField.Visible = 'off';
                    app.NmerodeclulasporcolumnasEditFieldLabel.Visible = 'off';
                    app.NmerodecolumnasEditField.Visible = 'off';
                    app.NmerodecolumnasEditFieldLabel.Visible = 'off';
                    app.NmerodepanelesdesplegadosporladoLabel.Visible = 'off';
                    app.NmerodepanelesdesplegadosporladoEditField.Visible =  'off';
                    app.NmerodeclulasporcupnLabel.Visible = 'on';
                    app.NmerodeclulasporcupnEditField.Visible = 'on';
                    app.NmerodecuponesporcolumnaLabel.Visible = 'on';
                    app.NmerodecuponesporcolumnaEditField.Visible = 'on';
                    app.NmerodecolumnasEditField_2Label.Visible = 'on';
                    app.NmerodecolumnasEditField_2.Visible = 'on';
                    app.NmerodepanelesdesplegadosporladoLabel.Visible = 'on';
                    app.NmerodepanelesdesplegadosporladoEditField.Visible =  'on';
                    
                    
            elseif (app.MaterialCompuestoCheckBox.Value)
                
                    app.AluminioCheckBox.Value=0;
                    app.NmerodeclulasporcupnLabel.Visible = 'off';
                    app.NmerodeclulasporcupnEditField.Visible = 'off';
                    app.NmerodecuponesporcolumnaLabel.Visible = 'off';
                    app.NmerodecuponesporcolumnaEditField.Visible = 'off';
                    app.NmerodecolumnasEditField_2Label.Visible = 'off';
                    app.NmerodecolumnasEditField_2.Visible = 'off';
                    app.NmerodepanelesdesplegadosporladoLabel.Visible = 'off';
                    app.NmerodepanelesdesplegadosporladoEditField.Visible =  'off';
                    app.NmerodeclulasporcolumnasEditField.Visible = 'on';
                    app.NmerodeclulasporcolumnasEditFieldLabel.Visible = 'on';
                    app.NmerodecolumnasEditField.Visible = 'on';
                    app.NmerodecolumnasEditFieldLabel.Visible = 'on';
                    app.NmerodepanelesdesplegadosporladoLabel.Visible = 'on';
                    app.NmerodepanelesdesplegadosporladoEditField.Visible =  'on';
                    
            else
                
                  
                    app.NmerodeclulasporcupnLabel.Visible = 'off';
                    app.NmerodeclulasporcupnEditField.Visible = 'off';
                    app.NmerodecuponesporcolumnaLabel.Visible = 'off';
                    app.NmerodecuponesporcolumnaEditField.Visible = 'off';
                    app.NmerodecolumnasEditField_2Label.Visible = 'off';
                    app.NmerodecolumnasEditField_2.Visible = 'off';
                    app.NmerodepanelesdesplegadosporladoLabel.Visible = 'off';
                    app.NmerodepanelesdesplegadosporladoEditField.Visible =  'off';
                    app.NmerodeclulasporcolumnasEditField.Visible = 'off';
                    app.NmerodeclulasporcolumnasEditFieldLabel.Visible = 'off';
                    app.NmerodecolumnasEditField.Visible = 'off';
                    app.NmerodecolumnasEditFieldLabel.Visible = 'off';
                    app.NmerodepanelesdesplegadosporladoLabel.Visible = 'off';
                    app.NmerodepanelesdesplegadosporladoEditField.Visible =  'off';
                
            end
            
        end

        % Value changed function: MaterialCompuestoCheckBox
        function MaterialCompuestoCheckBoxValueChanged(app, event)
            value = app.MaterialCompuestoCheckBox.Value;

                      
            switch value
                
                case 0
                    %app.AluminioCheckBox.Value = 1;
                    app.Espesor15mmButton.Visible = 'off';
                    app.Espesor25mmButton.Visible = 'off';
                    app.Nucleo1Button.Visible = 'off';
                    app.Nucleo2Button.Visible = 'off';


                case 1
                    %app.AluminioCheckBox.Value = 0;
                    app.Espesor15mmButton.Visible = 'off';
                    app.Espesor25mmButton.Visible = 'off';
                    app.Nucleo1Button.Visible = 'on';
                    app.Nucleo2Button.Visible = 'on';

                    
            end
            
            
            if (app.MaterialCompuestoCheckBox.Value)
                
                    app.AluminioCheckBox.Value=0;
                    app.NmerodeclulasporcupnLabel.Visible = 'off';
                    app.NmerodeclulasporcupnEditField.Visible = 'off';
                    app.NmerodecuponesporcolumnaLabel.Visible = 'off';
                    app.NmerodecuponesporcolumnaEditField.Visible = 'off';
                    app.NmerodecolumnasEditField_2Label.Visible = 'off';
                    app.NmerodecolumnasEditField_2.Visible = 'off';
                    app.NmerodepanelesdesplegadosporladoLabel.Visible = 'off';
                    app.NmerodepanelesdesplegadosporladoEditField.Visible =  'off';
                    app.NmerodeclulasporcolumnasEditField.Visible = 'on';
                    app.NmerodeclulasporcolumnasEditFieldLabel.Visible = 'on';
                    app.NmerodecolumnasEditField.Visible = 'on';
                    app.NmerodecolumnasEditFieldLabel.Visible = 'on';
                    app.NmerodepanelesdesplegadosporladoLabel.Visible = 'on';
                    app.NmerodepanelesdesplegadosporladoEditField.Visible =  'on';
                    
            elseif (app.AluminioCheckBox.Value)
                
                    app.MaterialCompuestoCheckBox.Value=0;
                    app.NmerodeclulasporcolumnasEditField.Visible = 'off';
                    app.NmerodeclulasporcolumnasEditFieldLabel.Visible = 'off';
                    app.NmerodecolumnasEditField.Visible = 'off';
                    app.NmerodecolumnasEditFieldLabel.Visible = 'off';
                    app.NmerodepanelesdesplegadosporladoLabel.Visible = 'off';
                    app.NmerodepanelesdesplegadosporladoEditField.Visible =  'off';
                    app.NmerodeclulasporcupnLabel.Visible = 'on';
                    app.NmerodeclulasporcupnEditField.Visible = 'on';
                    app.NmerodecuponesporcolumnaLabel.Visible = 'on';
                    app.NmerodecuponesporcolumnaEditField.Visible = 'on';
                    app.NmerodecolumnasEditField_2Label.Visible = 'on';
                    app.NmerodecolumnasEditField_2.Visible = 'on';
                    app.NmerodepanelesdesplegadosporladoLabel.Visible = 'on';
                    app.NmerodepanelesdesplegadosporladoEditField.Visible =  'on';
                    
             else
                
                  
                    app.NmerodeclulasporcupnLabel.Visible = 'off';
                    app.NmerodeclulasporcupnEditField.Visible = 'off';
                    app.NmerodecuponesporcolumnaLabel.Visible = 'off';
                    app.NmerodecuponesporcolumnaEditField.Visible = 'off';
                    app.NmerodecolumnasEditField_2Label.Visible = 'off';
                    app.NmerodecolumnasEditField_2.Visible = 'off';
                    app.NmerodepanelesdesplegadosporladoLabel.Visible = 'off';
                    app.NmerodepanelesdesplegadosporladoEditField.Visible =  'off';
                    app.NmerodeclulasporcolumnasEditField.Visible = 'off';
                    app.NmerodeclulasporcolumnasEditFieldLabel.Visible = 'off';
                    app.NmerodecolumnasEditField.Visible = 'off';
                    app.NmerodecolumnasEditFieldLabel.Visible = 'off';
                    app.NmerodepanelesdesplegadosporladoLabel.Visible = 'off';
                    app.NmerodepanelesdesplegadosporladoEditField.Visible =  'off';
                
                
            end
        end

        % Button pushed function: ClculodePotenciaButton
        function ClculodePotenciaButtonPushed(app, event)
           if (app.MaterialCompuestoCheckBox.Value)
               
               cell_number = 2*app.NmerodeclulasporcolumnasEditField.Value*app.NmerodecolumnasEditField.Value*app.NmerodepanelesdesplegadosporladoEditField.Value;
               Area = 0.002651*cell_number;
               Power = Area*0.295*1367;
               n_row = app.NmerodeclulasporcolumnasEditField.Value;
               n_col = app.NmerodecolumnasEditField.Value;
               
               app.VmpVEditField.Value = app.NmerodeclulasporcolumnasEditField.Value*2.411;
               app.ImpAEditField.Value = app.NmerodecolumnasEditField.Value*app.NmerodepanelesdesplegadosporladoEditField.Value*0.422;
               
               
           else
               
               cell_number = 2*app.NmerodeclulasporcupnEditField.Value*app.NmerodecuponesporcolumnaEditField.Value*app.NmerodecolumnasEditField_2.Value*app.NmerodepanelesdesplegadosporladoEditField.Value;
               Area = 0.002651*cell_number;
               Power = Area*0.295*1367;
               n_row = app.NmerodeclulasporcupnEditField.Value*app.NmerodecuponesporcolumnaEditField.Value;
               n_col = app.NmerodecolumnasEditField_2.Value;
               app.VmpVEditField.Value = app.NmerodeclulasporcupnEditField.Value*2.411;
               app.ImpAEditField.Value = app.NmerodecuponesporcolumnaEditField.Value*app.NmerodecolumnasEditField_2.Value*app.NmerodepanelesdesplegadosporladoEditField.Value*0.422;
            
           end

          
         app.PotenciaWTextArea.Value = num2str(Power);
         
         %%FUNCION PARA REPRESENTAR LA POSICIÓN DE LAS CELULAS SOLARES%%
         
            %delete("satelideimagen.png");


            l1 = 2;
            l2 = 1;
            l4 = 0.25;
            l3 = l2-l4;
            margin = l4;
            
            points_x = [0.0,  l1, l1, l1-l4  l4, 0.0, 0.0];
            points_y = [0.0, 0.0, l3,    l2, l2,  l3, 0.0];
            h=figure('visible','off');
            hold on;
            for i_row = 1: n_row
                for i_col = 1: n_col
                    x_transf = margin + points_x + (l1+margin)*(i_col-1);
                    y_transf = margin + points_y + (l2+margin)*(i_row-1);
                    h=plot(x_transf, y_transf, 'k', "linewidth", 2.0);      
                    
                end
            end
            
            box 
            max_x = (l1+margin)*(n_col) + margin;
            max_y = (l2+margin)*(n_row) + margin;
            
            box_x = [0.0, max_x, max_x, 0.0, 0.0];
            box_y = [0.0, 0.0, max_y, max_y, 0.0];
            h=plot(box_x, box_y, 'b', "linewidth", 1.0);
            
            xlim([0.0, max_x])
            ylim([0.0, max_y])
            box off
            set(gca,'visible','off')
            set(gca,'xtick',[])
            
            pbaspect([max_x max_y 1])
            saveas(h,'panel_celulas_imagen.png','png')


%             fig = uifigure;
%             app.Image = uiimage(fig);
            %ax = uiaxes(fig);
            %ax = uiaxes(fig,'Position',[10 10 550 400]);
            
            app.Image.Enable = true;
            app.Image.Visible = 'on';
            img0 = imread('panel_celulas_imagen.png');
            app.Image.ImageSource = img0;
           %im.ScaleMethod = 'fill'
           %im.Position =  [max_x max_y max_x max_y]
           %app.Image.DeleteFcn;
           %app.Image.ImageSource="satelideimagen.png";
           %app.Image.Visible = "on";
           
           %app.Image = imread('satelideimagen.png',"png");
           %app.Image.ImageSource = imshow(app.Image);


        end

        % Value changed function: 
        % AmpliarmargenlateralpanelesCheckBox
        function AmpliarmargenlateralpanelesCheckBoxValueChanged(app, event)
            value = app.AmpliarmargenlateralpanelesCheckBox.Value;
            
            switch value
                
                case 0
                    
                     app.f_lbSlider.Visible = 'off';
                     app.f_lhSlider.Visible = 'off';
                     app.f_lbSliderLabel.Visible = 'off';
                     app.f_lhSliderLabel.Visible = 'off';
                    
                case 1


                     app.f_lbSlider.Visible = 'on';
                     app.f_lhSlider.Visible = 'on';
                     app.f_lbSliderLabel.Visible = 'on';
                     app.f_lhSliderLabel.Visible = 'on';
                     
            end
            
        end

        % Callback function
        function ActualizarimagenButtonPushed(app, event)
            app.Image.ImageSource="satelideimagen.png";
        end

        % Value changed function: 
        % MostrarinformacinconexioneselctricasCheckBox
        function MostrarinformacinconexioneselctricasCheckBoxValueChanged(app, event)
            value = app.MostrarinformacinconexioneselctricasCheckBox.Value;
            
            
             switch value
                
                case 0
                    
                    app.ConexioneselctricaspaneldealuminioLabel.Visible = 'off';
                    app.ConexioneselctricaspaneldealuminioLabel_2.Visible = 'off';
                    app.ConexioneselctricaspaneldematerialcompuestoLabel.Visible = 'off';
                    app.ClulasenunamismacolumanaserieLabel.Visible = 'off';
                     
                    
                case 1
                    
                    
                    if (app.AluminioCheckBox.Value)
                        
                       app.ConexioneselctricaspaneldealuminioLabel.Visible = 'on';
                       app.ConexioneselctricaspaneldealuminioLabel_2.Visible = 'on';
                       
                    end
                    
                    if (app.MaterialCompuestoCheckBox.Value)
                        
                       app.ConexioneselctricaspaneldematerialcompuestoLabel.Visible = 'on';
                       app.ClulasenunamismacolumanaserieLabel.Visible = 'on';
                        
                    end


            end
        end

        % Value changed function: SCheckBox
        function SCheckBoxValueChanged(app, event)
            value = app.SCheckBox.Value;
            
            
            switch value
                
                case 0
                    app.Imagenodesplegado.Visible = 'on';
                    app.Imagedesplegado.Visible = 'off';
                 
                    
                case 1
                    app.Imagenodesplegado.Visible = 'off';
                    app.Imagedesplegado.Visible = 'on';

                    
            end
            
            if (app.SCheckBox.Value)
                
                app.NoCheckBox.Value = 0;
                %app.SCheckBox_2.Visible = 'on';
                %app.NoCheckBox_2.Enable = 'on';
                app.PanelesOrientadosButtonGroup.Visible = 'on';
                
            else
                
                app.NoCheckBox.Value = 1;
                
            end
            
            
        end

        % Value changed function: NoCheckBox
        function NoCheckBoxValueChanged(app, event)
            value = app.NoCheckBox.Value;
            
            
                  switch value
                
                case 0
                    app.Imagenodesplegado.Visible = 'off';
                    app.Imagedesplegado.Visible = 'on';
                 
                    
                case 1
                    app.Imagenodesplegado.Visible = 'on';
                    app.Imagedesplegado.Visible = 'off ';

                    
                  end
                  
                   if (app.NoCheckBox.Value)
                     
                        app.SCheckBox.Value = 0;
                        app.SCheckBox_2.Value = 0;
                        app.NoCheckBox_2.Value = 1;
                        app.PanelesOrientadosButtonGroup.Visible = 'off';
                        app.Imageorientado.Visible = 'off';
                        app.angulodeorientacinEditField.Visible = 'off';
                        app.ngulodeorientacinEditFieldLabel.Visible = 'off';
%                         app.SCheckBox_2.Visible = 'off';
%                         app.NCheckBox_2.Visible = 'off';

                   else 
                       
                       app.SCheckBox.Value = 1;
                
                   end
            
        end

        % Value changed function: SCheckBox_2
        function SCheckBox_2ValueChanged(app, event)
            value = app.SCheckBox_2.Value;
            
             switch value
                
                case 0
                    app.Imageorientado.Visible = 'off';
                    app.angulodeorientacinEditField.Visible = 'off';
                    app.ngulodeorientacinEditFieldLabel.Visible = 'off';


                case 1
                    app.Imageorientado.Visible = 'on';
                    app.angulodeorientacinEditField.Visible = 'on';
                    app.ngulodeorientacinEditFieldLabel.Visible = 'on';


            end
            
            if (app.SCheckBox_2.Value)
                
                app.NoCheckBox_2.Value = 0;
                
            else
                
                app.NoCheckBox_2.Value = 1;
                
            end
            
        end

        % Value changed function: NoCheckBox_2
        function NoCheckBox_2ValueChanged(app, event)
            value = app.NoCheckBox_2.Value;
            
               
             switch value
                
                case 0
                    app.Imageorientado.Visible = 'on';
                    app.angulodeorientacinEditField.Visible = 'on';
                    app.ngulodeorientacinEditFieldLabel.Visible = 'on';


                case 1
                    app.Imageorientado.Visible = 'off';
                    app.angulodeorientacinEditField.Visible = 'off';
                    app.ngulodeorientacinEditFieldLabel.Visible = 'off';


            end
            
            if (app.NoCheckBox_2.Value)
                
                app.SCheckBox_2.Value = 0;
            else
                app.SCheckBox_2.Value = 1;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            YN = uiconfirm(app.UIFigure,'Asegúrese de guardar los cambios antes de cerrar. ¿Quiere cerrar la aplicación?', 'Close request');
               if strcmpi(YN,'OK')
                      delete(app)
               end
        end

        % Button pushed function: GuardarcambiosButton
        function GuardarcambiosButtonPushed(app, event)
            
            
            %%MATERIAL
            nucleo='no seleccionado material';
            espesor='no seleccionado material';
            material_usado='no_seleccionado';
            num_panel_desplegados = app.NmerodepanelesdesplegadosporladoEditField.Value;
            
            Long_p = app.EditField.Value*app.f_lbSlider.Value;
            Alt_p = app.EditField_2.Value*app.f_lhSlider.Value;
            
            
              if (app.MaterialCompuestoCheckBox.Value)
                  material_usado = 'Compuesto';
                  
                  if (app.ButtonGroup_2.SelectedObject == app.Nucleo1Button)
                      
                 
                         nucleo = 1;
                         
                  elseif (app.ButtonGroup_2.SelectedObject == app.Nucleo2mButton)
                      
                         nucleo = 2;
                         
                  else
                      
                      nucleo = 'campo vacio';
                  end
                  
                  Nc_h_matcomp = app.NmerodecolumnasEditField.Value;
                  Nc_v = app.NmerodeclulasporcolumnasEditField.Value;
                  N_cupones=0;
                  Nc_h_al = 0;
                  Ncel_cupon = 0;
                    
                  
              elseif (app.AluminioCheckBox.Value)
                  
                  material_usado = 'Alumnio';
                  
                  if (app.ButtonGroup_2.SelectedObject == app.Espesor15mmButton)
                         espesor = 15;
                  elseif (app.ButtonGroup_2.SelectedObject == app.Espesor25mmButton)
                         espesor = 25;
                         
                  else 
                      espesor = 'campo vacio';
                  end
                  
                  Nc_h_al = app.NmerodecolumnasEditField_2.Value;
                  Ncel_cupon = app.NmerodeclulasporcupnEditField.Value;
                  N_cupones = app.NmerodecuponesporcolumnaEditField.Value;
                  Nc_h_matcomp = 0;
                  Nc_v=0;
                  
              end
              
            %% CONFIGURACIÓN DESPLIEGUE Y ORIENTACIÓN %%
            
            if (app.SCheckBox.Value)
                
                despliegue = 'sí';
                     
                if (app.SCheckBox_2.Value)
                   
                    orientable = 'sí';
                    angulo = app.angulodeorientacinEditField.Value;
                    
                elseif (app.NoCheckBox_2.Value)
                    
                    orientable = 'No';
                    
                else
                    
                    orientable = 'No seleccionado';
                    
                end
                
                
            elseif (app.NoCheckBox.Value)
                
              despliegue = 'No';
              angulo = 0;
              orientable = 'No';
      
               
            end
            
            param = [{material_usado},{espesor},{nucleo}, {Long_p}, {Alt_p},...
                 {Nc_h_matcomp}, {Nc_v}, {Nc_h_al}, {Ncel_cupon}, {N_cupones},{num_panel_desplegados},{despliegue},{orientable},{angulo}];
        
            xlswrite('Paramertos_Test.xls', param, 1, 'A2');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeometrayMaterialTab = uitab(app.TabGroup);
            app.GeometrayMaterialTab.Title = 'Geometría y Material';
            app.PANELESSOLARESLabel = uilabel(app.GeometrayMaterialTab);
            app.PANELESSOLARESLabel.Text = 'PANELES SOLARES';
            app.MaterialButtonGroup = uibuttongroup(app.GeometrayMaterialTab);
            app.MaterialButtonGroup.Title = 'Material';
            app.ButtonGroup_2 = uibuttongroup(app.MaterialButtonGroup);
            app.Espesor15mmButton = uiradiobutton(app.ButtonGroup_2);
            app.Espesor15mmButton.Visible = 'off';
            app.Espesor15mmButton.Text = 'Espesor  1,5 mm';
            app.Espesor15mmButton.Value = true;
            app.Espesor25mmButton = uiradiobutton(app.ButtonGroup_2);
            app.Espesor25mmButton.Visible = 'off';
            app.Espesor25mmButton.Text = 'Espesor 2,5 mm';
            app.AluminioCheckBox = uicheckbox(app.MaterialButtonGroup);
            app.AluminioCheckBox.ValueChangedFcn = createCallbackFcn(app, @AluminioCheckBoxValueChanged, true);
            app.AluminioCheckBox.Text = 'Aluminio';
            app.AluminioCheckBox.Value = true;
            app.MaterialCompuestoCheckBox = uicheckbox(app.MaterialButtonGroup);
            app.MaterialCompuestoCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaterialCompuestoCheckBoxValueChanged, true);
            app.MaterialCompuestoCheckBox.Text = 'Material Compuesto';
            app.ButtonGroup_3 = uibuttongroup(app.MaterialButtonGroup);
            app.Nucleo1Button = uiradiobutton(app.ButtonGroup_3);
            app.Nucleo1Button.Visible = 'off';
            app.Nucleo1Button.Text = 'Nucleo 1';
            app.Nucleo1Button.Value = true;
            app.Nucleo2Button = uiradiobutton(app.ButtonGroup_3);
            app.Nucleo2Button.Visible = 'off';
            app.Nucleo2Button.Text = 'Nucleo 2';
            app.GeometraSatlitePanel = uipanel(app.GeometrayMaterialTab);
            app.GeometraSatlitePanel.Title = 'Geometría Satélite';
            app.LongitudbasedelsatlitelbmmLabel = uilabel(app.GeometraSatlitePanel);
            app.LongitudbasedelsatlitelbmmLabel.Text = 'Longitud base del satélite (lb) [mm]';
            app.EditField = uieditfield(app.GeometraSatlitePanel, 'numeric');
            app.EditField.Limits = [300 400];
            app.EditField.Value = 400;
            app.LongitudalturadelsatlitelhmmLabel = uilabel(app.GeometraSatlitePanel);
            app.LongitudalturadelsatlitelhmmLabel.Text = 'Longitud altura del satélite (lh) [mm]';
            app.EditField_2 = uieditfield(app.GeometraSatlitePanel, 'numeric');
            app.EditField_2.Limits = [300 450];
            app.EditField_2.Value = 450;
            app.f_lbSliderLabel = uilabel(app.GeometraSatlitePanel);
            app.f_lbSliderLabel.Visible = 'off';
            app.f_lbSliderLabel.Text = 'f_lb';
            app.f_lbSlider = uislider(app.GeometraSatlitePanel);
            app.f_lbSlider.Limits = [1 1.1];
            app.f_lbSlider.Visible = 'off';
            app.f_lbSlider.Value = 1;
            app.f_lhSliderLabel = uilabel(app.GeometraSatlitePanel);
            app.f_lhSliderLabel.Visible = 'off';
            app.f_lhSliderLabel.Text = 'f_lh';
            app.f_lhSlider = uislider(app.GeometraSatlitePanel);
            app.f_lhSlider.Limits = [1 1.1];
            app.f_lhSlider.Visible = 'off';
            app.f_lhSlider.Value = 1;
            app.ButtonGroup = uibuttongroup(app.GeometraSatlitePanel);
            app.HexagonalButton_2 = uiradiobutton(app.ButtonGroup);
            app.HexagonalButton_2.Text = 'Hexagonal ';
            app.HexagonalButton_2.Value = true;
            app.RectangularButton_2 = uiradiobutton(app.ButtonGroup);
            app.RectangularButton_2.Text = 'Rectangular';
            app.AmpliarmargenlateralpanelesCheckBox = uicheckbox(app.GeometraSatlitePanel);
            app.AmpliarmargenlateralpanelesCheckBox.ValueChangedFcn = createCallbackFcn(app, @AmpliarmargenlateralpanelesCheckBoxValueChanged, true);
            app.AmpliarmargenlateralpanelesCheckBox.Text = 'Ampliar margen lateral paneles';
            app.Image2 = uiimage(app.GeometrayMaterialTab);
            app.Image2.ImageSource = 'image18.png';
            app.PotenciaTab = uitab(app.TabGroup);
            app.PotenciaTab.Title = 'Potencia';
            app.HTML = uihtml(app.PotenciaTab);
            app.HTML.HTMLSource = 'Nota :El valor de potencia en función de la configuración elegida se ha calculado para  T = 28ºC, un ángulo incidente del sol con respcto al panel de 0º y para  C =1367 W/m2';
            app.HTML2 = uihtml(app.PotenciaTab);
            app.HTML2.HTMLSource = 'Carácterísticas células solares:  Vmp = 2411 mV  Imp =  442mA';
            app.ConfiguracinclulassolaresencadapanelButtonGroup = uibuttongroup(app.PotenciaTab);
            app.ConfiguracinclulassolaresencadapanelButtonGroup.Title = 'Configuración células solares en cada panel (Elegir antes el material)';
            app.NmerodecolumnasEditFieldLabel = uilabel(app.ConfiguracinclulassolaresencadapanelButtonGroup);
            app.NmerodecolumnasEditFieldLabel.Visible = 'off';
            app.NmerodecolumnasEditFieldLabel.Text = 'Número de columnas';
            app.NmerodecolumnasEditField = uieditfield(app.ConfiguracinclulassolaresencadapanelButtonGroup, 'numeric');
            app.NmerodecolumnasEditField.Visible = 'off';
            app.NmerodeclulasporcolumnasEditFieldLabel = uilabel(app.ConfiguracinclulassolaresencadapanelButtonGroup);
            app.NmerodeclulasporcolumnasEditFieldLabel.Visible = 'off';
            app.NmerodeclulasporcolumnasEditFieldLabel.Text = 'Número de células por columnas';
            app.NmerodeclulasporcolumnasEditField = uieditfield(app.ConfiguracinclulassolaresencadapanelButtonGroup, 'numeric');
            app.NmerodeclulasporcolumnasEditField.Visible = 'off';
            app.NmerodepanelesdesplegadosporladoLabel = uilabel(app.ConfiguracinclulassolaresencadapanelButtonGroup);
            app.NmerodepanelesdesplegadosporladoLabel.Visible = 'off';
            app.NmerodepanelesdesplegadosporladoLabel.Text = {'Número de paneles'; ' desplegados por lado '};
            app.NmerodepanelesdesplegadosporladoEditField = uieditfield(app.ConfiguracinclulassolaresencadapanelButtonGroup, 'numeric');
            app.NmerodepanelesdesplegadosporladoEditField.Visible = 'off';
            app.NmerodeclulasporcupnLabel = uilabel(app.ConfiguracinclulassolaresencadapanelButtonGroup);
            app.NmerodeclulasporcupnLabel.Visible = 'off';
            app.NmerodeclulasporcupnLabel.Text = {'Número de células '; 'por cupón'};
            app.NmerodeclulasporcupnEditField = uieditfield(app.ConfiguracinclulassolaresencadapanelButtonGroup, 'numeric');
            app.NmerodeclulasporcupnEditField.Visible = 'off';
            app.NmerodecuponesporcolumnaLabel = uilabel(app.ConfiguracinclulassolaresencadapanelButtonGroup);
            app.NmerodecuponesporcolumnaLabel.Visible = 'off';
            app.NmerodecuponesporcolumnaLabel.Text = {'Número de cupones'; ' por columna'};
            app.NmerodecuponesporcolumnaEditField = uieditfield(app.ConfiguracinclulassolaresencadapanelButtonGroup, 'numeric');
            app.NmerodecuponesporcolumnaEditField.Visible = 'off';
            app.NmerodecolumnasEditField_2Label = uilabel(app.ConfiguracinclulassolaresencadapanelButtonGroup);
            app.NmerodecolumnasEditField_2Label.Visible = 'off';
            app.NmerodecolumnasEditField_2Label.Text = 'Número de columnas';
            app.NmerodecolumnasEditField_2 = uieditfield(app.ConfiguracinclulassolaresencadapanelButtonGroup, 'numeric');
            app.NmerodecolumnasEditField_2.Visible = 'off';
            app.ClculodePotenciaButton = uibutton(app.ConfiguracinclulassolaresencadapanelButtonGroup, 'push');
            app.ClculodePotenciaButton.ButtonPushedFcn = createCallbackFcn(app, @ClculodePotenciaButtonPushed, true);
            app.ClculodePotenciaButton.Text = 'Cálculo de Potencia';
            app.PotenciaWTextAreaLabel = uilabel(app.PotenciaTab);
            app.PotenciaWTextAreaLabel.Text = 'Potencia [W]';
            app.PotenciaWTextArea = uitextarea(app.PotenciaTab);
            app.PotenciaWTextArea.Editable = 'off';
            app.Image = uiimage(app.PotenciaTab);
            app.Image.Visible = 'off';
            app.ConexioneselctricaspaneldealuminioLabel = uilabel(app.PotenciaTab);
            app.ConexioneselctricaspaneldealuminioLabel.Visible = 'off';
            app.ConexioneselctricaspaneldealuminioLabel.Text = {'Células dentro de un mismo cupón : serie.'; 'Entre cupones: paralelo'; 'Entre panles desplegados: paralelo'};
            app.ConexioneselctricaspaneldealuminioLabel_2 = uilabel(app.PotenciaTab);
            app.ConexioneselctricaspaneldealuminioLabel_2.Visible = 'off';
            app.ConexioneselctricaspaneldealuminioLabel_2.Text = 'Conexiones eléctricas panel de aluminio:';
            app.VmpVEditFieldLabel = uilabel(app.PotenciaTab);
            app.VmpVEditFieldLabel.Text = 'Vmp [V]';
            app.VmpVEditField = uieditfield(app.PotenciaTab, 'numeric');
            app.VmpVEditField.Editable = 'off';
            app.ImpAEditFieldLabel = uilabel(app.PotenciaTab);
            app.ImpAEditFieldLabel.Text = 'Imp [A]';
            app.ImpAEditField = uieditfield(app.PotenciaTab, 'numeric');
            app.ImpAEditField.Editable = 'off';
            app.TensineIntensidaddelpanelenelpuntodemximapotenciaT28CLabel = uilabel(app.PotenciaTab);
            app.TensineIntensidaddelpanelenelpuntodemximapotenciaT28CLabel.Text = {' Tensión e Intensidad del panel '; 'en el punto de máxima potencia (T = 28ºC)'};
            app.MostrarinformacinconexioneselctricasCheckBox = uicheckbox(app.PotenciaTab);
            app.MostrarinformacinconexioneselctricasCheckBox.ValueChangedFcn = createCallbackFcn(app, @MostrarinformacinconexioneselctricasCheckBoxValueChanged, true);
            app.MostrarinformacinconexioneselctricasCheckBox.Text = 'Mostrar información conexiones eléctricas';
            app.ConexioneselctricaspaneldematerialcompuestoLabel = uilabel(app.PotenciaTab);
            app.ConexioneselctricaspaneldematerialcompuestoLabel.Visible = 'off';
            app.ConexioneselctricaspaneldematerialcompuestoLabel.Text = 'Conexiones eléctricas panel de material compuesto';
            app.ClulasenunamismacolumanaserieLabel = uilabel(app.PotenciaTab);
            app.ClulasenunamismacolumanaserieLabel.Visible = 'off';
            app.ClulasenunamismacolumanaserieLabel.Text = {'Células en una misma columana :  serie'; 'Entre columnas: paralelo'; 'Entre paneres: paralelo'};
            app.DespliegueyorientacinTab = uitab(app.TabGroup);
            app.DespliegueyorientacinTab.Title = 'Despliegue y orientación';
            app.HTML3 = uihtml(app.DespliegueyorientacinTab);
            app.HTML3.HTMLSource = 'Elegir la configuración en la que se quiere mostrar los paneles:';
            app.PanelesDesplagadosButtonGroup = uibuttongroup(app.DespliegueyorientacinTab);
            app.PanelesDesplagadosButtonGroup.Title = 'Paneles Desplagados';
            app.SCheckBox = uicheckbox(app.PanelesDesplagadosButtonGroup);
            app.SCheckBox.ValueChangedFcn = createCallbackFcn(app, @SCheckBoxValueChanged, true);
            app.SCheckBox.Text = 'Sí';
            app.NoCheckBox = uicheckbox(app.PanelesDesplagadosButtonGroup);
            app.NoCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoCheckBoxValueChanged, true);
            app.NoCheckBox.Text = 'No';
            app.NoCheckBox.Value = true;
            app.ngulodeorientacinEditFieldLabel = uilabel(app.DespliegueyorientacinTab);
            app.ngulodeorientacinEditFieldLabel.Visible = 'off';
            app.ngulodeorientacinEditFieldLabel.Text = 'Ángulo de orientación';
            app.angulodeorientacinEditField = uieditfield(app.DespliegueyorientacinTab, 'numeric');
            app.angulodeorientacinEditField.Limits = [-10 10];
            app.angulodeorientacinEditField.Visible = 'off';
            app.PanelesOrientadosButtonGroup = uibuttongroup(app.DespliegueyorientacinTab);
            app.PanelesOrientadosButtonGroup.Title = 'Paneles Orientados';
            app.PanelesOrientadosButtonGroup.Visible = 'off';
            app.SCheckBox_2 = uicheckbox(app.PanelesOrientadosButtonGroup);
            app.SCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @SCheckBox_2ValueChanged, true);
            app.SCheckBox_2.Text = 'Sí';
            app.NoCheckBox_2 = uicheckbox(app.PanelesOrientadosButtonGroup);
            app.NoCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @NoCheckBox_2ValueChanged, true);
            app.NoCheckBox_2.Text = 'No';
            app.NoCheckBox_2.Value = true;
            app.Imagenodesplegado = uiimage(app.DespliegueyorientacinTab);
            app.Imagenodesplegado.Visible = 'off';
            app.Imagenodesplegado.ImageSource = 'panel_plegado.JPG';
            app.Imagedesplegado = uiimage(app.DespliegueyorientacinTab);
            app.Imagedesplegado.Visible = 'off';
            app.Imagedesplegado.ImageSource = 'panel_desplegado.JPG';
            app.Imageorientado = uiimage(app.DespliegueyorientacinTab);
            app.Imageorientado.Visible = 'off';
            app.Imageorientado.ImageSource = 'oreintable.JPG';
            app.GuardarcambiosButton = uibutton(app.DespliegueyorientacinTab, 'push');
            app.GuardarcambiosButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarcambiosButtonPushed, true);
            app.GuardarcambiosButton.Text = 'Guardar cambios ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_pbl_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end