classdef gui_pbl_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        GeometrayMaterialTab           matlab.ui.container.Tab
        PANELESSOLARESLabel            matlab.ui.control.Label
        MaterialButtonGroup            matlab.ui.container.ButtonGroup
        EspesorButtonGroup             matlab.ui.container.ButtonGroup
        mmButton                       matlab.ui.control.RadioButton
        mmButton_2                     matlab.ui.control.RadioButton
        AluminioCheckBox               matlab.ui.control.CheckBox
        MaterialCompuestoCheckBox      matlab.ui.control.CheckBox
        NcleoButtonGroup               matlab.ui.container.ButtonGroup
        Polimero1Button                matlab.ui.control.RadioButton
        SandwichAluminio1Button        matlab.ui.control.RadioButton
        SandwichAlumnio2Button         matlab.ui.control.RadioButton
        EspesorncleommSpinnerLabel     matlab.ui.control.Label
        EspesorncleommSpinner          matlab.ui.control.Spinner
        FibraButtonGroup               matlab.ui.container.ButtonGroup
        Paratodosloscasoselespesordelsfibraser05mmLabel  matlab.ui.control.Label
        GeometraSatlitePanel           matlab.ui.container.Panel
        LongitudbasedelsatlitelbmmLabel  matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        LongitudalturadelsatlitelhmmLabel  matlab.ui.control.Label
        EditField_2                    matlab.ui.control.NumericEditField
        f_lbLabel                      matlab.ui.control.Label
        f_lbSlider                     matlab.ui.control.Slider
        f_lhLabel                      matlab.ui.control.Label
        f_lhSlider                     matlab.ui.control.Slider
        ButtonGroup                    matlab.ui.container.ButtonGroup
        HexagonalButton_2              matlab.ui.control.RadioButton
        RectangularButton_2            matlab.ui.control.RadioButton
        SobredimensinmximaLabel        matlab.ui.control.Label
        PotenciaTab                    matlab.ui.container.Tab
        HTML                           matlab.ui.control.HTML
        HTML2                          matlab.ui.control.HTML
        ConfiguracinclulassolaresencadapanelButtonGroup  matlab.ui.container.ButtonGroup
        ClculodePotenciaButton         matlab.ui.control.Button
        MaterialCompuestoPanel         matlab.ui.container.Panel
        NmerodecuponesporcolumnaSpinner_2Label  matlab.ui.control.Label
        NmerodecuponesporcolumnaSpinner_2  matlab.ui.control.Spinner
        NmerodeclulasporcupnSpinner_2  matlab.ui.control.Spinner
        NmerodeclulasporcupnSpinner_2Label  matlab.ui.control.Label
        NmerodecolumnasSpinner_2Label  matlab.ui.control.Label
        NmerodecolumnasSpinner_2       matlab.ui.control.Spinner
        NmerodepanelesdesplegadosporladoLabel_2  matlab.ui.control.Label
        NmerodepanelesdesplegadosporladoSpinner  matlab.ui.control.Spinner
        AlumnioPanel                   matlab.ui.container.Panel
        NmerodecuponesporcolumnaLabel_2  matlab.ui.control.Label
        NmerodecuponesporcolumnaSpinner  matlab.ui.control.Spinner
        NmerodecolumnasSpinnerLabel    matlab.ui.control.Label
        NmerodecolumnasSpinner         matlab.ui.control.Spinner
        NmerodeclulasporcupnLabel_2    matlab.ui.control.Label
        NmerodeclulasporcupnSpinner    matlab.ui.control.Spinner
        PotenciaWTextAreaLabel         matlab.ui.control.Label
        PotenciaWTextArea              matlab.ui.control.TextArea
        Image                          matlab.ui.control.Image
        ConexioneselctricaspaneldealuminioLabel  matlab.ui.control.Label
        ConexioneselctricaspaneldealuminioLabel_2  matlab.ui.control.Label
        VmpVEditFieldLabel             matlab.ui.control.Label
        VmpVEditField                  matlab.ui.control.NumericEditField
        ImpAEditFieldLabel             matlab.ui.control.Label
        ImpAEditField                  matlab.ui.control.NumericEditField
        TensineIntensidaddelpanelenelpuntodemximapotenciaT28CLabel  matlab.ui.control.Label
        MostrarinformacinconexioneselctricasCheckBox  matlab.ui.control.CheckBox
        ConexioneselctricaspaneldematerialcompuestoLabel  matlab.ui.control.Label
        ClulasenunamismacolumanaserieLabel  matlab.ui.control.Label
        DespliegueyorientacinTab       matlab.ui.container.Tab
        HTML3                          matlab.ui.control.HTML
        PanelesDesplagadosButtonGroup  matlab.ui.container.ButtonGroup
        SCheckBox                      matlab.ui.control.CheckBox
        NoCheckBox                     matlab.ui.control.CheckBox
        ngulodeorientacinEditFieldLabel  matlab.ui.control.Label
        angulodeorientacinEditField    matlab.ui.control.NumericEditField
        PanelesOrientadosButtonGroup   matlab.ui.container.ButtonGroup
        SCheckBox_2                    matlab.ui.control.CheckBox
        NoCheckBox_2                   matlab.ui.control.CheckBox
        Imagenodesplegado              matlab.ui.control.Image
        Imagedesplegado                matlab.ui.control.Image
        Imageorientado                 matlab.ui.control.Image
        GuardarcambiosButton           matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AluminioCheckBox
        function AluminioCheckBoxValueChanged(app, event)
            value = app.AluminioCheckBox.Value;
            
            %CHECK BUTTON DEL ALUMINIO DE LA PRIMERA PESTAÑA
            %ESTA PARTE AFECTA A LA PARTE DE LAS OPCIONES DEL MATERIAL 

            switch value
                
                case 0
                    app.MaterialCompuestoCheckBox.Value=1;
                    
                    %Hace visible el panel correspondiente al material
                    app.NcleoButtonGroup.Visible = 'on';
                    app.FibraButtonGroup.Visible = 'on';
                    app.EspesorButtonGroup.Visible = 'off';
                    

                case 1
                    app.MaterialCompuestoCheckBox.Value=0;
                    
                    %Hace visible el panel correspondiente al material
                    app.EspesorButtonGroup.Visible = 'on';
                    app.NcleoButtonGroup.Visible = 'off';
                    app.FibraButtonGroup.Visible = 'off';


            end
            
            %ESTA PARTE AFECTA A UNICAMENTE A LA PARTE DE LA ELECCIÓN DE
            %CÉLULAS
            if (app.AluminioCheckBox.Value)
                
                    %Para que no puedan estar los dos Checkbuttom señalados
                    %a la vez
                    app.MaterialCompuestoCheckBox.Value=0;
                    

                    %Hace visible el panel correspondiente de las
                    %conexiones eléctricas
                    app.MaterialCompuestoPanel.Visible =  'off';
                    app.AlumnioPanel.Visible = 'on'; 
                    
                    
            elseif (app.MaterialCompuestoCheckBox.Value)
                
                    %Para que no puedan estar los dos Checkbuttom señalados
                    %a la vez
                    app.AluminioCheckBox.Value=0;
                    
                    %Hace visible el panel correspondiente de las
                    %conexiones eléctricas

                    app.MaterialCompuestoPanel.Visible =  'on';
                    app.AlumnioPanel.Visible = 'off'; 


            else

                
            end
            
%                   Obligar a actualizar la info de las conexiones
%                     electricas en caso de que se deje el check puesto -NO
%                     VA
%                     
%             if (app.MostrarinformacinconexioneselctricasCheckBox.Value)
%                 
%                 app.MostrarinformacinconexioneselctricasCheckBox.Value=0;
%                 app.MostrarinformacinconexioneselctricasCheckBox.Value=1;
%                 
%             else
%                         
%                 app.MostrarinformacinconexioneselctricasCheckBox.Value=1;
%                 app.MostrarinformacinconexioneselctricasCheckBox.Value=0;
%                         
%             end
%                    
            
        end

        % Value changed function: MaterialCompuestoCheckBox
        function MaterialCompuestoCheckBoxValueChanged(app, event)
            value = app.MaterialCompuestoCheckBox.Value;

            %CHECK BUTTON DEL ALUMINIO DE LA PRIMERA PESTAÑA
            %ESTA PARTE AFECTA A LA PARTE DE LAS OPCIONES DEL MATERIAL 
                      
            switch value
                
                case 0
                    app.AluminioCheckBox.Value = 1;
                    
                    %Hace visible el panel correspondiente al material
                    app.EspesorButtonGroup.Visible = 'on';
                    app.NcleoButtonGroup.Visible = 'off';
                    app.FibraButtonGroup.Visible = 'off';


                case 1
                    
                    app.AluminioCheckBox.Value = 0;
                    
                    %Hace visible el panel correspondiente al material
                    app.NcleoButtonGroup.Visible = 'on';
                    app.FibraButtonGroup.Visible = 'on';
                    app.EspesorButtonGroup.Visible = 'off';

                    
            end
            
            %ESTA PARTE AFECTA A UNICAMENTE A LA PARTE DE LA ELECCIÓN DE
            %CÉLULAS
            
            if (app.MaterialCompuestoCheckBox.Value)
                
                    %Para que no puedan estar los dos Checkbuttom señalados
                    %a la vez
                    app.AluminioCheckBox.Value=0;
                    
                    %Hace visible el panel correspondiente de las
                    %conexiones eléctricas
                    app.MaterialCompuestoPanel.Visible =  'on';
                    app.AlumnioPanel.Visible = 'off'; 
                    
            elseif (app.AluminioCheckBox.Value)
                    
                    %Para que no puedan estar los dos Checkbuttom señalados
                    %a la vez
                    app.MaterialCompuestoCheckBox.Value=0;
                    
                     %Hace visible el panel correspondiente de las
                    %conexiones eléctricas
                    app.MaterialCompuestoPanel.Visible =  'on';
                    app.AlumnioPanel.Visible = 'off';                    
             else


            end
            
                    %Obligar a actualizar la info de las conexiones
                    %electricas en caso de que se deje el check puesto NO VA 
%                     
%                     if (app.MostrarinformacinconexioneselctricasCheckBox.Value)
%                         app.MostrarinformacinconexioneselctricasCheckBox.Value=0;
%                         app.MostrarinformacinconexioneselctricasCheckBox.Value=1;
%                     else
%                         
%                         app.MostrarinformacinconexioneselctricasCheckBox.Value=1;
%                         app.MostrarinformacinconexioneselctricasCheckBox.Value=0;
%                         
%                     end
                
        end

        % Button pushed function: ClculodePotenciaButton
        function ClculodePotenciaButtonPushed(app, event)
           
            %Botón que calcula la potencia, las tensión e intensidad en
            %funcion de las conexiones eléctricas y muestra un
            %esquematicado de la posición geométrica de las celulas.
            
            
            if (app.MaterialCompuestoCheckBox.Value)
                
                %Calculo de la potencia con el número de células elegidas.
               
               cell_number = 2*app.NmerodecuponesporcolumnaSpinner_2.Value*app.NmerodeclulasporcupnSpinner_2.Value*app.NmerodecolumnasSpinner_2.Value*app.NmerodepanelesdesplegadosporladoSpinner.Value;
               Area = 0.002651*cell_number;
               Power = Area*0.294595*1367;
               
               %Este valor es el necesario para la función que representa
               %las células solares
               n_row = app.NmerodecuponesporcolumnaSpinner_2.Value*app.NmerodeclulasporcupnSpinner_2.Value;
               n_col = app.NmerodecolumnasSpinner_2.Value;
               
               %Cálculo de la tensión y de la intensidad para las
               %conexiones eléctricas del material compuesto.
               
               app.VmpVEditField.Value = app.NmerodeclulasporcupnSpinner_2.Value*2.411;
               app.ImpAEditField.Value = app.NmerodecuponesporcolumnaSpinner_2.Value*app.NmerodecolumnasSpinner_2.Value*app.NmerodepanelesdesplegadosporladoSpinner.Value*0.4428;
               
               
            else  %Para el caso del aluminio
               
                
               %Calculo de la potencia con el número de células elegidas.
               cell_number = 2*app.NmerodeclulasporcupnSpinner.Value*app.NmerodecuponesporcolumnaSpinner.Value*app.NmerodecolumnasSpinner.Value*app.NmerodepanelesdesplegadosporladoSpinner.Value;
               Area = 0.002651*cell_number;
               Power = Area*0.294595*1367;
               
               
               %Este valor es el necesario para la función que representa
               %las células solares
               n_row = app.NmerodeclulasporcupnSpinner.Value*app.NmerodecuponesporcolumnaSpinner.Value;
               n_col = app.NmerodecolumnasSpinner.Value;
               
               %Cálculo de la tensión y de la intensidad para las
               %conexiones eléctricas del material compuesto.
               app.VmpVEditField.Value = app.NmerodeclulasporcupnSpinner.Value*2.411;
               app.ImpAEditField.Value = app.NmerodecuponesporcolumnaSpinner.Value*app.NmerodecolumnasSpinner.Value*app.NmerodepanelesdesplegadosporladoSpinner.Value*0.4428;
            
           end

          
         app.PotenciaWTextArea.Value = num2str(Power); %Valor que sale por pantalla
         
         %%FUNCIÓN PARA REPRESENTAR LA POSICIÓN DE LAS CELULAS SOLARES%%


            %Geometría de la célula
            l1 = 2;
            l2 = 1;
            l4 = 0.25;
            l3 = l2-l4;
            margin = l4; %margenes entre células
            
            %Representación de la célula
            points_x = [0.0,  l1, l1, l1-l4  l4, 0.0, 0.0];
            points_y = [0.0, 0.0, l3,    l2, l2,  l3, 0.0];
            
            
            h=figure('visible','off');
            hold on;
            
            %Pinta todas las células
            for i_row = 1: n_row
                for i_col = 1: n_col
                    x_transf = margin + points_x + (l1+margin)*(i_col-1);
                    y_transf = margin + points_y + (l2+margin)*(i_row-1);
                    h=plot(x_transf, y_transf, 'k', "linewidth", 2.0);      
                    
                end
            end
            
            
            %Pinta los bordes 
            
            box 
            max_x = (l1+margin)*(n_col) + margin;
            max_y = (l2+margin)*(n_row) + margin;
            
            box_x = [0.0, max_x, max_x, 0.0, 0.0];
            box_y = [0.0, 0.0, max_y, max_y, 0.0];
            h=plot(box_x, box_y, 'b', "linewidth", 1.0);
            
            xlim([0.0, max_x])
            ylim([0.0, max_y])
            box off
            set(gca,'visible','off')
            set(gca,'xtick',[])
            
            pbaspect([max_x max_y 1])
            saveas(h,'panel_celulas_imagen.png','png') %Sobreescribe el archivo para que cada vez que se cambia la configuración se pueda actualizar 


            %Carga la imagen por pantalla
            app.Image.Enable = true;
            app.Image.Visible = 'on';
            img0 = imread('panel_celulas_imagen.png');
            app.Image.ImageSource = img0;


        end

        % Value changed function: 
        % MostrarinformacinconexioneselctricasCheckBox
        function MostrarinformacinconexioneselctricasCheckBoxValueChanged(app, event)
            value = app.MostrarinformacinconexioneselctricasCheckBox.Value;
            
            %ACTIVA O DESACTIVA EL CUADRO INFORMATIVO DE LAS CONEXIONES
            %ELÉCTRICAS EN FUNCIÓN DEL TIPO DE MATERIAL 
             switch value
                
                case 0
                    
                    app.ConexioneselctricaspaneldealuminioLabel.Visible = 'off';
                    app.ConexioneselctricaspaneldealuminioLabel_2.Visible = 'off';
                    app.ConexioneselctricaspaneldematerialcompuestoLabel.Visible = 'off';
                    app.ClulasenunamismacolumanaserieLabel.Visible = 'off';
                     
                    
                case 1
                    
                    
                    if (app.AluminioCheckBox.Value)
                        
                       app.ConexioneselctricaspaneldealuminioLabel.Visible = 'on';
                       app.ConexioneselctricaspaneldealuminioLabel_2.Visible = 'on';
                       app.ConexioneselctricaspaneldematerialcompuestoLabel.Visible = 'off';
                       app.ClulasenunamismacolumanaserieLabel.Visible = 'off';


                    elseif (app.MaterialCompuestoCheckBox.Value)
                        
                       app.ConexioneselctricaspaneldematerialcompuestoLabel.Visible = 'on';
                       app.ClulasenunamismacolumanaserieLabel.Visible = 'on';
                       app.ConexioneselctricaspaneldealuminioLabel.Visible = 'off';
                       app.ConexioneselctricaspaneldealuminioLabel_2.Visible = 'off';
                       
                    else

                     
                    end


            end
        end

        % Value changed function: SCheckBox
        function SCheckBoxValueChanged(app, event)
            value = app.SCheckBox.Value;
            
            %Boton de desplegado _Sí
            
            
            switch value
                
                case 0
                    app.Imagenodesplegado.Visible = 'on';
                    app.Imagedesplegado.Visible = 'off';
                 
                    
                case 1
                    app.Imagenodesplegado.Visible = 'off';
                    app.Imagedesplegado.Visible = 'on';

                    
            end
            
            if (app.SCheckBox.Value)
                
                app.NoCheckBox.Value = 0;
                app.PanelesOrientadosButtonGroup.Visible = 'on';
                
            else
                
                app.NoCheckBox.Value = 1;
                
            end
            
            
        end

        % Value changed function: NoCheckBox
        function NoCheckBoxValueChanged(app, event)
            value = app.NoCheckBox.Value;
            
            %Botón desplegado-NO
            
            
                  switch value
                
                case 0
                    app.Imagenodesplegado.Visible = 'off';
                    app.Imagedesplegado.Visible = 'on';
                 
                    
                case 1
                    app.Imagenodesplegado.Visible = 'on';
                    app.Imagedesplegado.Visible = 'off ';

                    
                  end
                  
                   if (app.NoCheckBox.Value)
                     
                        app.SCheckBox.Value = 0;
                        app.SCheckBox_2.Value = 0;
                        app.NoCheckBox_2.Value = 1;
                        app.PanelesOrientadosButtonGroup.Visible = 'off';
                        app.Imageorientado.Visible = 'off';
                        app.angulodeorientacinEditField.Visible = 'off';
                        app.ngulodeorientacinEditFieldLabel.Visible = 'off';


                   else 
                       
                       app.SCheckBox.Value = 1;
                
                   end
            
        end

        % Value changed function: SCheckBox_2
        function SCheckBox_2ValueChanged(app, event)
            value = app.SCheckBox_2.Value;
            
            %Botón de orientado-Sí
            
             switch value
                
                case 0
                    app.Imageorientado.Visible = 'off';
                    app.angulodeorientacinEditField.Visible = 'off';
                    app.ngulodeorientacinEditFieldLabel.Visible = 'off';


                case 1
                    app.Imageorientado.Visible = 'on';
                    app.angulodeorientacinEditField.Visible = 'on';
                    app.ngulodeorientacinEditFieldLabel.Visible = 'on';


            end
            
            if (app.SCheckBox_2.Value)
                
                app.NoCheckBox_2.Value = 0;
                
            else
                
                app.NoCheckBox_2.Value = 1;
                
            end
            
        end

        % Value changed function: NoCheckBox_2
        function NoCheckBox_2ValueChanged(app, event)
            value = app.NoCheckBox_2.Value;
            
            %Botón de orientado -NO
            
               
             switch value
                
                case 0
                    app.Imageorientado.Visible = 'on';
                    app.angulodeorientacinEditField.Visible = 'on';
                    app.ngulodeorientacinEditFieldLabel.Visible = 'on';


                case 1
                    app.Imageorientado.Visible = 'off';
                    app.angulodeorientacinEditField.Visible = 'off';
                    app.ngulodeorientacinEditFieldLabel.Visible = 'off';


            end
            
            if (app.NoCheckBox_2.Value)
                
                app.SCheckBox_2.Value = 0;
            else
                app.SCheckBox_2.Value = 1;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            YN = uiconfirm(app.UIFigure,'Asegúrese de guardar los cambios antes de cerrar. ¿Quiere cerrar la aplicación?', 'Close request');
               if strcmpi(YN,'OK')
                      delete(app)
               end
        end

        % Button pushed function: GuardarcambiosButton
        function GuardarcambiosButtonPushed(app, event)
            
            
            %VUELCA TODOS LOS VALORES SELECCIONADOS EN EL EXCELL.
            
            %%MATERIAL
            nucleo='no seleccionado material';
            espesor='no seleccionado material';
            material_usado='no_seleccionado';
            num_panel_desplegados = app.NmerodepanelesdesplegadosporladoSpinner.Value;
            angulo=0; %Si no se modifica el valor inical del ángulo
            
            Long_p = app.EditField.Value*(100+app.f_lbSlider.Value)/100;
            Alt_p = app.EditField_2.Value*(100+app.f_lhSlider.Value)/100;
            
              %Geometría del satélite
              
              if (app.ButtonGroup.SelectedObject == app.HexagonalButton_2)
                  
                 geometria = 'Hexagono';
              else
                  geometria = 'Rectángulo';
              end
              
              %Material
            
            
              if (app.MaterialCompuestoCheckBox.Value)
                  material_usado = 'Compuesto';
                  espesor = app.EspesorncleommSpinner.Value;
                  
                  if (app.EspesorButtonGroup.SelectedObject == app.Polimero1Button)
                      
                 
                         nucleo = 1;
                         
                  elseif (app.EspesorButtonGroup.SelectedObject == app.SandwichAluminio1Button)
                      
                         nucleo = 2;
                         
                  else
                      
                         nucleo = 3;
                  end
                  
                  N_cells_per_Cupon_Comp = app.NmerodecolumnasSpinner_2.Value;
                  N_Cupon_Filas_Comp = app.NmerodecuponesporcolumnaSpinner_2.Value;
                  N_Cupon_Columnas_Comp= app.NmerodeclulasporcupnSpinner_2.Value;
                  N_Cupon_Filas_Al=0;
                  N_cells_per_Cupon_Al = 0;
                  N_Cupon_Columnas_Al = 0;
                  Panel_Dim_X_al = 0;
                  Panel_Dim_Y_al = 0;
                  
                  
               %Cálculo de la longitud mínima del panel para el panel de
                %material compuesto:
                
                %Margenes compuesto  
                  Offset_Y = 5;
                  
                  Offset_X = 12;
    
                 % Bisagra
                  Hinge_Dim_X = 2*9;

                 % Dimensiones de un cupón
                  Cell_Dim_X = 80;    % mm

                  Cell_Dim_Y =  40; %mm
                  
                  Panel_Dim_X_comp = N_cells_per_Cupon_Comp*Cell_Dim_X + 2*Hinge_Dim_X + ...
                                      2*Offset_X + (N_cells_per_Cupon_Comp-1)*1;  %L2
                  
                  Panel_Dim_Y_comp = N_Cupon_Filas_Comp*N_Cupon_Columnas_Comp*Cell_Dim_Y  + ...
                                     2*Offset_Y + N_Cupon_Filas_Comp*(N_Cupon_Columnas_Comp-1)*1 ...
                                      + (N_Cupon_Filas_Comp-1)*4;   %L1
                    
                  
              elseif (app.AluminioCheckBox.Value)
                  
                  material_usado = 'Alumnio';
                  
                  if (app.EspesorButtonGroup.SelectedObject == app.mmButton)
                         espesor = 1.5;
                  elseif (app.EspesorButtonGroup.SelectedObject == app.mmButton_2)
                         espesor = 2.5;
                         
                  else 
                      espesor = 'campo vacio';
                  end
                  
                  N_cells_per_Cupon_Al = app.NmerodecolumnasSpinner.Value;
                  N_Cupon_Columnas_Al = app.NmerodeclulasporcupnSpinner.Value;
                  N_Cupon_Filas_Al = app.NmerodecuponesporcolumnaSpinner.Value;
                  N_cells_per_Cupon_Comp = 0;
                  N_Cupon_Filas_Comp = 0;
                  N_Cupon_Columnas_Comp= 0;
                  Panel_Dim_X_comp = 0;
                  Panel_Dim_Y_comp = 0;
                  
              %Cálculo de la longitud mínima del panel para el panel de
              %aluminio:
                  
                % Margenes entre cupon y borde del panel
                Offset_X = 1;
                Offset_Y = 1;
                  
                % Dimensiones de un cupon
                Cupon_Dim_X = 84;    % mm
                Cupon_Dim_Y =  (N_Cupon_Columnas_Al - 1)*41 + 52;
                
                % Bisagra
                Hinge_Dim_X = 2*9;

                
                % Dimensiones finales del panel
                Panel_Dim_X_al = N_cells_per_Cupon_Al*Cupon_Dim_X + 1*(N_cells_per_Cupon_Al - 1)...
                             + 2*Offset_X + 2*Hinge_Dim_X;
                Panel_Dim_Y_al = N_Cupon_Filas_Al*Cupon_Dim_Y + 1*(N_Cupon_Filas_Al - 1)...
                             + 2*Offset_Y;
                  
              end
              
            %% CONFIGURACIÓN DESPLIEGUE Y ORIENTACIÓN %%
            
            if (app.SCheckBox.Value)
                
                despliegue = 'sí';
                     
                if (app.SCheckBox_2.Value)
                   
                    orientable = 'sí';
                    angulo = app.angulodeorientacinEditField.Value;
                    
                elseif (app.NoCheckBox_2.Value)
                    
                    orientable = 'No';
                    
                else
                    
                    orientable = 'No seleccionado';
                    
                end
                
            elseif (app.NoCheckBox.Value)
                
              despliegue = 'No';
              angulo = 0;
              orientable = 'No';

            end
            
            % DRV test
            %{
            if strcmp(material_usado,'Alumnio') == 1
                L_1 = Panel_Dim_Y_al;
                L_2 = Panel_Dim_X_al;
                N_cupon_col = N_Cupon_Columnas_Al;
                N_cupon_fil = N_Cupon_Filas_Al;
                N_cells_per_Cupon = N_cells_per_Cupon_Al;
                material_usado = 'Aluminium';
            else
                L_1 = Panel_Dim_X_comp;
                L_2 = Panel_Dim_X_comp;
                N_cupon_col = N_Cupon_Columnas_Comp;
                N_cupon_fil = N_Cupon_Filas_Comp;
                N_cells_per_Cupon = N_cells_per_Cupon_Comp;
            end
            param = [ {L_1}, {L_2}, {material_usado}, {espesor}, {nucleo},...
                      {geometria}, {N_cupon_col}, {N_cupon_fil}, {N_cells_per_Cupon},...
                      {num_panel_desplegados},{despliegue},{orientable},{angulo}];
                  
            xlswrite('Paramertos_Test_DRV.xls', param, 1, 'A2');
            %}
            
            param = [{Long_p},{Alt_p},{material_usado},{espesor},{nucleo},{geometria},...
                 {N_Cupon_Columnas_Comp}, {N_Cupon_Filas_Comp}, {N_cells_per_Cupon_Comp},{Panel_Dim_X_comp},{Panel_Dim_Y_comp},...
                 {N_cells_per_Cupon_Al}, {N_Cupon_Columnas_Al}, {N_Cupon_Filas_Al},{Panel_Dim_X_al},{Panel_Dim_Y_al},...
                 {num_panel_desplegados},{despliegue},{orientable},{angulo}];
        
            xlswrite('Paramertos_Test.xls', param, 1, 'A3');
            
        end

        % Value changing function: NmerodeclulasporcupnSpinner
        function NmerodeclulasporcupnSpinnerValueChanging(app, event)
            
            %ALUMINIO
            
            Cupon_Cells = event.Value;
            
            %Establecer límite para el número de cupones, una vez definidas
            %el número de células por cupón
             % Margenes entre cupon y borde del panel
            Offset_X = 1;
            Offset_Y = 1;
            
            %Dimensiones del cupón
            
            Cupon_Dim_X = 84;    % mm
            Cupon_Dim_Y =  (Cupon_Cells - 1)*41 + 52;
            
            Long_p = app.EditField.Value*(100+app.f_lbSlider.Value)/100;
            Alt_p = app.EditField_2.Value*(100+app.f_lhSlider.Value)/100;
            
            max_Cupon_N_Y = floor((Alt_p + 1 - 2*Offset_Y)/(Cupon_Dim_Y+1));
            
            if max_Cupon_N_Y == 1
                 max_Cupon_N_Y = 1.1;
                 max_initial=1;
                 
            elseif max_Cupon_N_Y == 0
                 %no deberia ocurrir ya que fuerzo a que no lo haga
                 max_Cupon_N_Y = 1.1;
                 max_initial=1;
                 app.NmerodecuponesporcolumnaSpinner.Value=1;
            else
                
                max_initial=1;
                
            end

            app.NmerodecuponesporcolumnaSpinner.Limits=[max_initial  max_Cupon_N_Y];


        end

        % Value changing function: NmerodecuponesporcolumnaSpinner
        function NmerodecuponesporcolumnaSpinnerValueChanging(app, event)
%             changingValue = event.Value;
%             app.NmerodecuponesporcolumnaSpinner.Limits=[app.NmerodeclulasporcupnSpinner.Value+1 app.NmerodeclulasporcupnSpinner.Value+3];
%             
        end

        % Value changing function: NmerodecolumnasSpinner
        function NmerodecolumnasSpinnerValueChanging(app, event)
          
            % Bisagra
            Hinge_Dim_X = 2*9;
            
            % Margenes entre cupon y borde del panel
            Offset_X = 1;
            Offset_Y = 1;
            
             % Dimensiones de un cupon
            Cupon_Dim_X = 84;    % mm
            
            %Obtención del número máximo de colummas que caben en función
            %de la longitud del panel.
            Long_p = app.EditField.Value*(100 + app.f_lbSlider.Value)/100;
            
            max_Cupon_N_X = floor((Long_p + 1-2*Hinge_Dim_X-2*Offset_X)/(Cupon_Dim_X+1));
            
            app.NmerodecolumnasSpinner.Limits=[1 max_Cupon_N_X];
            
        end

        % Value changed function: NmerodeclulasporcupnSpinner
        function NmerodeclulasporcupnSpinnerValueChanged(app, event)
            %ALUMINIO
            
            
            Alt_p = app.EditField_2.Value*(100+app.f_lhSlider.Value)/100;
            
              % Margenes entre cupon y borde del panel
            Offset_X = 1;
            Offset_Y = 1;
            
            %Cálculo del número máximo de células que cabrian en un cupón
            %para una determinada medida
            
            max_Cupon_Cells = floor((Alt_p + 41 -52 -2*Offset_Y)/41);
            app.NmerodeclulasporcupnSpinner.Limits=[1 max_Cupon_Cells];
             
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
%            
%             %ALUMINIO 
%             
%               
%             % Bisagra
%             Hinge_Dim_X = 2*9;
%             
%             % Margenes entre cupon y borde del panel
%             Offset_X = 1;
%             Offset_Y = 1;
%             
%              % Dimensiones de un cupon
%             Cupon_Dim_X = 84;    % mm
%             
%             %Obtención del número máximo de colummas que caben en función
%             %de la longitud del panel.
%             Long_p = app.EditField.Value*(100 + app.f_lbSlider.Value)/100;
%             
%             max_Cupon_N_X = floor((Long_p + 1-2*Hinge_Dim_X-2*Offset_X)/(Cupon_Dim_X+1));
%             
%             app.NmerodecolumnasSpinner.Limits=[1 max_Cupon_N_X];
%             
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
% 
%             %ALUMINIO
%             
%             
%             Alt_p = app.EditField_2.Value*(100+app.f_lhSlider.Value)/100;
%             
%               % Margenes entre cupon y borde del panel
%             Offset_X = 1;
%             Offset_Y = 1;
%             
%             %Cálculo del número máximo de células que cabrian en un cupón
%             %para una determinada medida
%             
%             max_Cupon_Cells = floor((Alt_p + 41 -52 -2*Offset_Y)/41);
%             app.NmerodeclulasporcupnSpinner.Limits=[1 max_Cupon_Cells];
            
        end

        % Value changing function: NmerodeclulasporcupnSpinner_2
        function NmerodeclulasporcupnSpinner_2ValueChanging(app, event)
            changingValue = event.Value;
            
                    %MATERIAL COMPUESTO 
            
            Cupon_Cells = event.Value;
            
            %Establecer límite para el número de cupones, una vez definidas
            %el número de células por cupón
             % Margenes entre cupon y borde del panel
            Offset_Y = 5;
            
            %Dimensiones del cupón

            Cell_Dim_Y =  40; %mm
            
            Alt_p = app.EditField_2.Value*(100+app.f_lhSlider.Value)/100;
            
            max_Cupon_N_Y = floor((Alt_p - 2*Offset_Y+4)/(Cell_Dim_Y*Cupon_Cells+(Cupon_Cells-1)*1+4));
            
            if max_Cupon_N_Y == 1
                 max_Cupon_N_Y = 1.1;
                 max_initial=1;
                 
            elseif max_Cupon_N_Y == 0
                 %no deberia ocurrir ya que fuerzo a que no lo haga
                 max_Cupon_N_Y = 1.1;
                 max_initial=1;
                 app.NmerodecuponesporcolumnaSpinner.Value=1;
            else
                
                max_initial=1;
                
            end

            app.NmerodecuponesporcolumnaSpinner_2.Limits=[max_initial  max_Cupon_N_Y];
            
        end

        % Value changed function: NmerodeclulasporcupnSpinner_2
        function NmerodeclulasporcupnSpinner_2ValueChanged(app, event)
           
                  %MATERIAL COMPUESTO
            
            
            Alt_p = app.EditField_2.Value*(100+app.f_lhSlider.Value)/100;
            
              % Margenes entre cupon
            Offset_Y = 5;
            
            %Cálculo del número máximo de células que cabrian en un cupón
            %para una determinada medida
            
            max_Cupon_Cells = floor((Alt_p -2*Offset_Y + 1)/(40+1));
            app.NmerodeclulasporcupnSpinner_2.Limits=[1 max_Cupon_Cells];
            
        end

        % Value changing function: NmerodecolumnasSpinner_2
        function NmerodecolumnasSpinner_2ValueChanging(app, event)


            % Bisagra
            Hinge_Dim_X = 2*9;
            
            % Margenes entre cupon y borde del panel
            Offset_X = 12;
           
             % Dimensiones de un cupon
            Cell_Dim_X = 80;    % mm
            
            %Obtención del número máximo de colummas que caben en función
            %de la longitud del panel.
            Long_p = app.EditField.Value*(100 + app.f_lbSlider.Value)/100;
            
            max_Cupon_N_X = floor((Long_p + 1-2*Hinge_Dim_X-2*Offset_X)/(Cell_Dim_X+1));
            
            app.NmerodecolumnasSpinner_2.Limits=[1 max_Cupon_N_X];


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeometrayMaterialTab = uitab(app.TabGroup);
            app.GeometrayMaterialTab.Title = 'Geometría y Material';
            app.PANELESSOLARESLabel = uilabel(app.GeometrayMaterialTab);
            app.PANELESSOLARESLabel.Text = 'PANELES SOLARES';
            app.MaterialButtonGroup = uibuttongroup(app.GeometrayMaterialTab);
            app.MaterialButtonGroup.Title = 'Material';
            app.EspesorButtonGroup = uibuttongroup(app.MaterialButtonGroup);
            app.EspesorButtonGroup.Title = 'Espesor';
            app.mmButton = uiradiobutton(app.EspesorButtonGroup);
            app.mmButton.Text = '  1,5 mm';
            app.mmButton.Value = true;
            app.mmButton_2 = uiradiobutton(app.EspesorButtonGroup);
            app.mmButton_2.Text = '  2,5 mm';
            app.AluminioCheckBox = uicheckbox(app.MaterialButtonGroup);
            app.AluminioCheckBox.ValueChangedFcn = createCallbackFcn(app, @AluminioCheckBoxValueChanged, true);
            app.AluminioCheckBox.Text = 'Aluminio';
            app.AluminioCheckBox.Value = true;
            app.MaterialCompuestoCheckBox = uicheckbox(app.MaterialButtonGroup);
            app.MaterialCompuestoCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaterialCompuestoCheckBoxValueChanged, true);
            app.MaterialCompuestoCheckBox.Text = 'Material Compuesto';
            app.NcleoButtonGroup = uibuttongroup(app.MaterialButtonGroup);
            app.NcleoButtonGroup.Title = 'Núcleo';
            app.NcleoButtonGroup.Visible = 'off';
            app.Polimero1Button = uiradiobutton(app.NcleoButtonGroup);
            app.Polimero1Button.Text = 'Polimero 1';
            app.Polimero1Button.Value = true;
            app.SandwichAluminio1Button = uiradiobutton(app.NcleoButtonGroup);
            app.SandwichAluminio1Button.Text = ' Sandwich Aluminio 1';
            app.SandwichAlumnio2Button = uiradiobutton(app.NcleoButtonGroup);
            app.SandwichAlumnio2Button.Text = ' Sandwich Alumnio 2';
            app.EspesorncleommSpinnerLabel = uilabel(app.NcleoButtonGroup);
            app.EspesorncleommSpinnerLabel.Text = 'Espesor núcleo [mm] ';
            app.EspesorncleommSpinner = uispinner(app.NcleoButtonGroup);
            app.EspesorncleommSpinner.Step = 0.5;
            app.EspesorncleommSpinner.Limits = [4.5 5];
            app.EspesorncleommSpinner.Value = 4.5;
            app.FibraButtonGroup = uibuttongroup(app.MaterialButtonGroup);
            app.FibraButtonGroup.Title = 'Fibra';
            app.FibraButtonGroup.Visible = 'off';
            app.Paratodosloscasoselespesordelsfibraser05mmLabel = uilabel(app.FibraButtonGroup);
            app.Paratodosloscasoselespesordelsfibraser05mmLabel.Text = {'Para todos los'; ' casos el espesor'; 'de ls fibra será'; '0.5 mm.'};
            app.GeometraSatlitePanel = uipanel(app.GeometrayMaterialTab);
            app.GeometraSatlitePanel.Title = 'Geometría Satélite';
            app.LongitudbasedelsatlitelbmmLabel = uilabel(app.GeometraSatlitePanel);
            app.LongitudbasedelsatlitelbmmLabel.Text = 'Longitud base del satélite (lb) [mm]';
            app.EditField = uieditfield(app.GeometraSatlitePanel, 'numeric');
            app.EditField.Limits = [300 400];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 400;
            app.LongitudalturadelsatlitelhmmLabel = uilabel(app.GeometraSatlitePanel);
            app.LongitudalturadelsatlitelhmmLabel.Text = 'Longitud altura del satélite (lh) [mm]';
            app.EditField_2 = uieditfield(app.GeometraSatlitePanel, 'numeric');
            app.EditField_2.Limits = [300 450];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 450;
            app.f_lbLabel = uilabel(app.GeometraSatlitePanel);
            app.f_lbLabel.Text = 'f_lb [%]';
            app.f_lbSlider = uislider(app.GeometraSatlitePanel);
            app.f_lbSlider.Limits = [0 10];
            app.f_lhLabel = uilabel(app.GeometraSatlitePanel);
            app.f_lhLabel.Text = 'f_lh [%]';
            app.f_lhSlider = uislider(app.GeometraSatlitePanel);
            app.f_lhSlider.Limits = [0 10];
            app.ButtonGroup = uibuttongroup(app.GeometraSatlitePanel);
            app.HexagonalButton_2 = uiradiobutton(app.ButtonGroup);
            app.HexagonalButton_2.Text = 'Hexagonal ';
            app.HexagonalButton_2.Value = true;
            app.RectangularButton_2 = uiradiobutton(app.ButtonGroup);
            app.RectangularButton_2.Text = 'Rectangular';
            app.SobredimensinmximaLabel = uilabel(app.GeometraSatlitePanel);
            app.SobredimensinmximaLabel.Text = 'Sobredimensión máxima';
            app.PotenciaTab = uitab(app.TabGroup);
            app.PotenciaTab.Title = 'Potencia';
            app.HTML = uihtml(app.PotenciaTab);
            app.HTML.HTMLSource = 'Nota :El valor de potencia en función de la configuración elegida se ha calculado para  T = 28ºC, un ángulo incidente del sol con respcto al panel de 0º y para  C =1367 W/m2';
            app.HTML2 = uihtml(app.PotenciaTab);
            app.HTML2.HTMLSource = 'Carácterísticas células solares:  Vmp = 2411 mV  Imp =  442mA';
            app.ConfiguracinclulassolaresencadapanelButtonGroup = uibuttongroup(app.PotenciaTab);
            app.ConfiguracinclulassolaresencadapanelButtonGroup.Title = 'Configuración células solares en cada panel (Elegir antes el material)';
            app.ClculodePotenciaButton = uibutton(app.ConfiguracinclulassolaresencadapanelButtonGroup, 'push');
            app.ClculodePotenciaButton.ButtonPushedFcn = createCallbackFcn(app, @ClculodePotenciaButtonPushed, true);
            app.ClculodePotenciaButton.Text = 'Cálculo de Potencia';
            app.MaterialCompuestoPanel = uipanel(app.ConfiguracinclulassolaresencadapanelButtonGroup);
            app.MaterialCompuestoPanel.Title = 'Material Compuesto';
            app.NmerodecuponesporcolumnaSpinner_2Label = uilabel(app.MaterialCompuestoPanel);
            app.NmerodecuponesporcolumnaSpinner_2Label.Text = {'Número de cupones '; 'por columna'};
            app.NmerodecuponesporcolumnaSpinner_2 = uispinner(app.MaterialCompuestoPanel);
            app.NmerodecuponesporcolumnaSpinner_2.Limits = [1 20];
            app.NmerodecuponesporcolumnaSpinner_2.ValueDisplayFormat = '%.0f';
            app.NmerodecuponesporcolumnaSpinner_2.Value = 1;
            app.NmerodeclulasporcupnSpinner_2 = uispinner(app.MaterialCompuestoPanel);
            app.NmerodeclulasporcupnSpinner_2.ValueChangingFcn = createCallbackFcn(app, @NmerodeclulasporcupnSpinner_2ValueChanging, true);
            app.NmerodeclulasporcupnSpinner_2.Limits = [1 10];
            app.NmerodeclulasporcupnSpinner_2.ValueChangedFcn = createCallbackFcn(app, @NmerodeclulasporcupnSpinner_2ValueChanged, true);
            app.NmerodeclulasporcupnSpinner_2.Value = 1;
            app.NmerodeclulasporcupnSpinner_2Label = uilabel(app.MaterialCompuestoPanel);
            app.NmerodeclulasporcupnSpinner_2Label.Text = {'Número de células '; 'por cupón'};
            app.NmerodecolumnasSpinner_2Label = uilabel(app.MaterialCompuestoPanel);
            app.NmerodecolumnasSpinner_2Label.Text = 'Número de columnas';
            app.NmerodecolumnasSpinner_2 = uispinner(app.MaterialCompuestoPanel);
            app.NmerodecolumnasSpinner_2.ValueChangingFcn = createCallbackFcn(app, @NmerodecolumnasSpinner_2ValueChanging, true);
            app.NmerodecolumnasSpinner_2.Limits = [1 20];
            app.NmerodecolumnasSpinner_2.Value = 1;
            app.NmerodepanelesdesplegadosporladoLabel_2 = uilabel(app.ConfiguracinclulassolaresencadapanelButtonGroup);
            app.NmerodepanelesdesplegadosporladoLabel_2.Text = {'Número de paneles '; 'desplegados por lado'};
            app.NmerodepanelesdesplegadosporladoSpinner = uispinner(app.ConfiguracinclulassolaresencadapanelButtonGroup);
            app.NmerodepanelesdesplegadosporladoSpinner.Limits = [1 10];
            app.NmerodepanelesdesplegadosporladoSpinner.Value = 1;
            app.AlumnioPanel = uipanel(app.ConfiguracinclulassolaresencadapanelButtonGroup);
            app.AlumnioPanel.Title = 'Alumnio';
            app.NmerodecuponesporcolumnaLabel_2 = uilabel(app.AlumnioPanel);
            app.NmerodecuponesporcolumnaLabel_2.Text = {'Número de cupones'; ' por columna'};
            app.NmerodecuponesporcolumnaSpinner = uispinner(app.AlumnioPanel);
            app.NmerodecuponesporcolumnaSpinner.ValueChangingFcn = createCallbackFcn(app, @NmerodecuponesporcolumnaSpinnerValueChanging, true);
            app.NmerodecuponesporcolumnaSpinner.Limits = [1 10];
            app.NmerodecuponesporcolumnaSpinner.ValueDisplayFormat = '%.0f';
            app.NmerodecuponesporcolumnaSpinner.Value = 1;
            app.NmerodecolumnasSpinnerLabel = uilabel(app.AlumnioPanel);
            app.NmerodecolumnasSpinnerLabel.Text = 'Número de columnas';
            app.NmerodecolumnasSpinner = uispinner(app.AlumnioPanel);
            app.NmerodecolumnasSpinner.ValueChangingFcn = createCallbackFcn(app, @NmerodecolumnasSpinnerValueChanging, true);
            app.NmerodecolumnasSpinner.Limits = [1 50];
            app.NmerodecolumnasSpinner.Value = 1;
            app.NmerodeclulasporcupnLabel_2 = uilabel(app.AlumnioPanel);
            app.NmerodeclulasporcupnLabel_2.Text = {'Número de células '; 'por cupón'};
            app.NmerodeclulasporcupnSpinner = uispinner(app.AlumnioPanel);
            app.NmerodeclulasporcupnSpinner.ValueChangingFcn = createCallbackFcn(app, @NmerodeclulasporcupnSpinnerValueChanging, true);
            app.NmerodeclulasporcupnSpinner.Limits = [1 20];
            app.NmerodeclulasporcupnSpinner.ValueChangedFcn = createCallbackFcn(app, @NmerodeclulasporcupnSpinnerValueChanged, true);
            app.NmerodeclulasporcupnSpinner.Value = 1;
            app.PotenciaWTextAreaLabel = uilabel(app.PotenciaTab);
            app.PotenciaWTextAreaLabel.Text = 'Potencia [W]';
            app.PotenciaWTextArea = uitextarea(app.PotenciaTab);
            app.PotenciaWTextArea.Editable = 'off';
            app.Image = uiimage(app.PotenciaTab);
            app.Image.Visible = 'off';
            app.ConexioneselctricaspaneldealuminioLabel = uilabel(app.PotenciaTab);
            app.ConexioneselctricaspaneldealuminioLabel.Visible = 'off';
            app.ConexioneselctricaspaneldealuminioLabel.Text = {'Células dentro de un mismo cupón : serie.'; 'Entre cupones: paralelo'; 'Entre panles desplegados: paralelo'};
            app.ConexioneselctricaspaneldealuminioLabel_2 = uilabel(app.PotenciaTab);
            app.ConexioneselctricaspaneldealuminioLabel_2.Visible = 'off';
            app.ConexioneselctricaspaneldealuminioLabel_2.Text = 'Conexiones eléctricas panel de aluminio:';
            app.VmpVEditFieldLabel = uilabel(app.PotenciaTab);
            app.VmpVEditFieldLabel.Text = 'Vmp [V]';
            app.VmpVEditField = uieditfield(app.PotenciaTab, 'numeric');
            app.VmpVEditField.Editable = 'off';
            app.ImpAEditFieldLabel = uilabel(app.PotenciaTab);
            app.ImpAEditFieldLabel.Text = 'Imp [A]';
            app.ImpAEditField = uieditfield(app.PotenciaTab, 'numeric');
            app.ImpAEditField.Editable = 'off';
            app.TensineIntensidaddelpanelenelpuntodemximapotenciaT28CLabel = uilabel(app.PotenciaTab);
            app.TensineIntensidaddelpanelenelpuntodemximapotenciaT28CLabel.Text = {' Tensión e Intensidad del panel '; 'en el punto de máxima potencia (T = 28ºC)'};
            app.MostrarinformacinconexioneselctricasCheckBox = uicheckbox(app.PotenciaTab);
            app.MostrarinformacinconexioneselctricasCheckBox.ValueChangedFcn = createCallbackFcn(app, @MostrarinformacinconexioneselctricasCheckBoxValueChanged, true);
            app.MostrarinformacinconexioneselctricasCheckBox.Text = 'Mostrar información conexiones eléctricas';
            app.ConexioneselctricaspaneldematerialcompuestoLabel = uilabel(app.PotenciaTab);
            app.ConexioneselctricaspaneldematerialcompuestoLabel.Visible = 'off';
            app.ConexioneselctricaspaneldematerialcompuestoLabel.Text = 'Conexiones eléctricas panel de material compuesto';
            app.ClulasenunamismacolumanaserieLabel = uilabel(app.PotenciaTab);
            app.ClulasenunamismacolumanaserieLabel.Visible = 'off';
            app.ClulasenunamismacolumanaserieLabel.Text = {'Número de células seleccionadas por cupón '; 'determina el número de células conectadas en serie'; ''; 'Entre columnas: paralelo'; 'Entre paneles: paralelo'};
            app.DespliegueyorientacinTab = uitab(app.TabGroup);
            app.DespliegueyorientacinTab.Title = 'Despliegue y orientación';
            app.HTML3 = uihtml(app.DespliegueyorientacinTab);
            app.HTML3.HTMLSource = 'Elegir la configuración en la que se quiere mostrar los paneles:';
            app.PanelesDesplagadosButtonGroup = uibuttongroup(app.DespliegueyorientacinTab);
            app.PanelesDesplagadosButtonGroup.Title = 'Paneles Desplagados';
            app.SCheckBox = uicheckbox(app.PanelesDesplagadosButtonGroup);
            app.SCheckBox.ValueChangedFcn = createCallbackFcn(app, @SCheckBoxValueChanged, true);
            app.SCheckBox.Text = 'Sí';
            app.NoCheckBox = uicheckbox(app.PanelesDesplagadosButtonGroup);
            app.NoCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoCheckBoxValueChanged, true);
            app.NoCheckBox.Text = 'No';
            app.NoCheckBox.Value = true;
            app.ngulodeorientacinEditFieldLabel = uilabel(app.DespliegueyorientacinTab);
            app.ngulodeorientacinEditFieldLabel.Visible = 'off';
            app.ngulodeorientacinEditFieldLabel.Text = 'Ángulo de orientación';
            app.angulodeorientacinEditField = uieditfield(app.DespliegueyorientacinTab, 'numeric');
            app.angulodeorientacinEditField.Limits = [-10 10];
            app.angulodeorientacinEditField.Visible = 'off';
            app.PanelesOrientadosButtonGroup = uibuttongroup(app.DespliegueyorientacinTab);
            app.PanelesOrientadosButtonGroup.Title = 'Paneles Orientados';
            app.PanelesOrientadosButtonGroup.Visible = 'off';
            app.SCheckBox_2 = uicheckbox(app.PanelesOrientadosButtonGroup);
            app.SCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @SCheckBox_2ValueChanged, true);
            app.SCheckBox_2.Text = 'Sí';
            app.NoCheckBox_2 = uicheckbox(app.PanelesOrientadosButtonGroup);
            app.NoCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @NoCheckBox_2ValueChanged, true);
            app.NoCheckBox_2.Text = 'No';
            app.NoCheckBox_2.Value = true;
            app.Imagenodesplegado = uiimage(app.DespliegueyorientacinTab);
            app.Imagenodesplegado.Visible = 'off';
            app.Imagenodesplegado.ImageSource = 'panel_plegado.JPG';
            app.Imagedesplegado = uiimage(app.DespliegueyorientacinTab);
            app.Imagedesplegado.Visible = 'off';
            app.Imagedesplegado.ImageSource = 'panel_desplegado.JPG';
            app.Imageorientado = uiimage(app.DespliegueyorientacinTab);
            app.Imageorientado.Visible = 'off';
            app.Imageorientado.ImageSource = 'oreintable.JPG';
            app.GuardarcambiosButton = uibutton(app.DespliegueyorientacinTab, 'push');
            app.GuardarcambiosButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarcambiosButtonPushed, true);
            app.GuardarcambiosButton.Text = 'Guardar cambios ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_pbl_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end